classdef EquiFlicker < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        EquiFlickerTaskUIFigure        matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        SettingsTab                    matlab.ui.container.Tab
        EquiFlickerTaskGeneralPanel    matlab.ui.container.Panel
        DistancecmEditFieldLabel       matlab.ui.control.Label
        DistancecmEditField            matlab.ui.control.NumericEditField
        PixelscmEditFieldLabel         matlab.ui.control.Label
        PixelscmEditField              matlab.ui.control.NumericEditField
        FixationXdegEditFieldLabel     matlab.ui.control.Label
        FixationXdegEditField          matlab.ui.control.NumericEditField
        ELDummySwitchLabel             matlab.ui.control.Label
        ELDummySwitch                  matlab.ui.control.RockerSwitch
        DebugModeSwitchLabel           matlab.ui.control.Label
        DebugModeSwitch                matlab.ui.control.RockerSwitch
        FixationYdegEditFieldLabel     matlab.ui.control.Label
        FixationYdegEditField          matlab.ui.control.NumericEditField
        FixInitiationsEditFieldLabel   matlab.ui.control.Label
        FixInitiationsEditField        matlab.ui.control.NumericEditField
        Fix1stDurationsEditFieldLabel  matlab.ui.control.Label
        Fix1stDurationsEditField       matlab.ui.control.NumericEditField
        FixationDiameterdEditFieldLabel  matlab.ui.control.Label
        FixationDiameterdEditField     matlab.ui.control.NumericEditField
        InfoEditFieldLabel             matlab.ui.control.Label
        InfoEditField                  matlab.ui.control.EditField
        SubjectEditFieldLabel          matlab.ui.control.Label
        SubjectEditField               matlab.ui.control.EditField
        SavePathEditFieldLabel         matlab.ui.control.Label
        SavePathEditField              matlab.ui.control.EditField
        CalibrationEditFieldLabel      matlab.ui.control.Label
        CalibrationEditField           matlab.ui.control.EditField
        CButton                        matlab.ui.control.Button
        SPButton                       matlab.ui.control.Button
        SendTriggersSwitchLabel        matlab.ui.control.Label
        SendTriggersSwitch             matlab.ui.control.RockerSwitch
        ColorDepthDropDownLabel        matlab.ui.control.Label
        ColorDepthDropDown             matlab.ui.control.DropDown
        EquiFlickerTaskStimulusOptionsPanel  matlab.ui.container.Panel
        FixedColorEditFieldLabel       matlab.ui.control.Label
        FixedColorEditField            matlab.ui.control.EditField
        VarColorStartEditFieldLabel    matlab.ui.control.Label
        VarColorStartEditField         matlab.ui.control.EditField
        VarColorEndEditFieldLabel      matlab.ui.control.Label
        VarColorEndEditField           matlab.ui.control.EditField
        VarColorStepsEditFieldLabel    matlab.ui.control.Label
        VarColorStepsEditField         matlab.ui.control.EditField
        SizedLabel                     matlab.ui.control.Label
        SizedEditField                 matlab.ui.control.NumericEditField
        TRIALSdurationsEditFieldLabel  matlab.ui.control.Label
        TRIALSdurationsEditField       matlab.ui.control.NumericEditField
        BackgroundColorEditFieldLabel  matlab.ui.control.Label
        BackgroundColorEditField       matlab.ui.control.EditField
        FrequencyHzEditFieldLabel      matlab.ui.control.Label
        FrequencyHzEditField           matlab.ui.control.NumericEditField
        EditFieldLabel                 matlab.ui.control.Label
        EditField                      matlab.ui.control.NumericEditField
        SpatialFrequencycdEditFieldLabel  matlab.ui.control.Label
        SpatialFrequencycdEditField    matlab.ui.control.NumericEditField
        intervalsEditFieldLabel        matlab.ui.control.Label
        intervalsEditField             matlab.ui.control.NumericEditField
        GratingTypeDropDownLabel       matlab.ui.control.Label
        GratingTypeDropDown            matlab.ui.control.DropDown
        MaskCheckBox                   matlab.ui.control.CheckBox
        ContrastMultiplierEditFieldLabel  matlab.ui.control.Label
        ContrastMultiplierEditField    matlab.ui.control.NumericEditField
        DKLCheckBox                    matlab.ui.control.CheckBox
        ShowButton                     matlab.ui.control.Button
        ControlPanel                   matlab.ui.container.Panel
        GOButton                       matlab.ui.control.Button
        SaveButton                     matlab.ui.control.Button
        ExitButton                     matlab.ui.control.Button
        ExperimentalRunTab             matlab.ui.container.Tab
        EMAxes                         matlab.ui.control.UIAxes
        EMAxesPower                    matlab.ui.control.UIAxes
        EMAxesAverage                  matlab.ui.control.UIAxes
        MoreSettingsTab                matlab.ui.container.Tab
        EyelinkPanel                   matlab.ui.container.Panel
        SampleRateKnobLabel            matlab.ui.control.Label
        SampleRateKnob                 matlab.ui.control.DiscreteKnob
        CalibPointsKnobLabel           matlab.ui.control.Label
        CalibPointsKnob                matlab.ui.control.DiscreteKnob
        TargetFixationOptionsPanel     matlab.ui.container.Panel
        TargetIntiationEditFieldLabel  matlab.ui.control.Label
        TargetIntiationEditField       matlab.ui.control.NumericEditField
        TargetMaintainEditFieldLabel   matlab.ui.control.Label
        TargetMaintainEditField        matlab.ui.control.NumericEditField
        TargetDiameterEditFieldLabel   matlab.ui.control.Label
        TargetDiameterEditField        matlab.ui.control.NumericEditField
        OthertrainingparametersPanel   matlab.ui.container.Panel
        InterTrialDelayEditFieldLabel  matlab.ui.control.Label
        InterTrialDelayEditField       matlab.ui.control.NumericEditField
        FixOnlyRewardEditFieldLabel    matlab.ui.control.Label
        FixOnlyRewardEditField         matlab.ui.control.NumericEditField
        ExclusionZoneEditFieldLabel    matlab.ui.control.Label
        ExclusionZoneEditField         matlab.ui.control.EditField
        ArduinoPanel                   matlab.ui.container.Panel
        SerialPortNameEditFieldLabel   matlab.ui.control.Label
        SerialPortNameEditField        matlab.ui.control.EditField
        SendRewardSwitchLabel          matlab.ui.control.Label
        SendRewardSwitch               matlab.ui.control.RockerSwitch
    end

 
 properties (Access = public)
  ana % parsed settings
 end
 
 methods (Access = private)
  
  function parseSettings(app)
       app.ana = [];
       app.ana.taskName = "EquiMotion";
       app.ana.uuid = num2str(dec2hex(floor((now - floor(now))*1e10)));
       app.ana.subject = app.SubjectEditField.Value;
       app.ana.comments = app.InfoEditField.Value;
       app.ana.ResultDir = app.SavePathEditField.Value;
       app.ana.gammaTable = app.CalibrationEditField.Value;
       app.ana.pixelsPerCm = app.PixelscmEditField.Value;
       app.ana.distance = app.DistancecmEditField.Value;
       app.ana.fixX = app.FixationXdegEditField.Value;
       app.ana.fixY = app.FixationYdegEditField.Value;
       app.ana.firstFixInit = app.FixInitiationsEditField.Value;
       app.ana.firstFixDiameter = app.FixationDiameterdEditField.Value;
       app.ana.firstFixTime = app.Fix1stDurationsEditField.Value;
       app.ana.backgroundColor = str2num(app.BackgroundColorEditField.Value);
       app.ana.colorFixed = str2num(app.FixedColorEditField.Value);
       app.ana.colorStart = str2num(app.VarColorStartEditField.Value);
       app.ana.colorEnd = str2num(app.VarColorEndEditField.Value);
       app.ana.colorStep = str2num(app.VarColorStepsEditField.Value);
       app.ana.size = app.SizedEditField.Value;
       app.ana.sf = app.SpatialFrequencycdEditField.Value;
       app.ana.contrastMultiplier = app.ContrastMultiplierEditField.Value;
       app.ana.trialDuration = app.TRIALSdurationsEditField.Value;
       app.ana.trialInterval = app.intervalsEditField.Value;
       app.ana.trialNumber = app.EditField.Value;
       app.ana.frequency = app.FrequencyHzEditField.Value;
       app.ana.sampleRate = str2num(app.SampleRateKnob.Value);
       app.ana.calibrationStyle = app.CalibPointsKnob.Value;
       app.ana.arduinoPort = app.SerialPortNameEditField.Value;
       app.ana.bitDepth = app.ColorDepthDropDown.Value;
       app.ana.type = app.GratingTypeDropDown.Value;
       app.ana.mask = logical(app.MaskCheckBox.Value);
       
       if strcmpi(app.DebugModeSwitch.Value,'On')
        app.ana.debug = true;
        app.ana.windowed = [0 0 1000 1000];
       else
        app.ana.debug = false;
        app.ana.windowed = [];
       end
       if strcmpi(app.ELDummySwitch.Value,'On')
        app.ana.isDummy = true;
       else
        app.ana.isDummy = false;
       end
       if strcmpi(app.SendTriggersSwitch.Value,'On')
           app.ana.sendTrigger = true;
       else
           app.ana.sendTrigger = false;
       end
       if strcmpi(app.SendRewardSwitch.Value,'On')
           app.ana.sendReward = true;
       else
           app.ana.sendReward = false;
       end
       app.ana.plotAxis1 = app.EMAxes;
       app.ana.plotAxis2 = app.EMAxesPower;
       app.ana.plotAxis3 = app.EMAxesAverage;
  end

 end
 

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function setPath(app)
            s=stimulusSequence();
            app.SavePathEditField.Value=s.paths.savedData;
        end

        % Button pushed function: CButton
        function CButtonPushed(app, event)
            [f, p] = uigetfile('*.mat','Select a Calibration File');
            app.CalibrationEditField.Value = [p f];
        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
           delete(app);
        end

        % Button pushed function: GOButton
        function GOButtonPushed(app, event)
           event.Source.BusyAction = 'cancel';
           event.Source.Interruptible = 'off';
           app.parseSettings();
           fprintf('\n=========GO BUTTON PUSHED============\n')
           runEquiFlicker(app.ana)
        end

        % Button pushed function: SPButton
        function SPButtonPushed(app, event)
           app.SavePathEditField.Value = uigetdir;
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
           app.parseSettings();
        end

        % Value changed function: FrequencyHzEditField
        function FrequencyHzEditFieldValueChanged(app, event)
            value = app.FrequencyHzEditField.Value;
        end

        % Button pushed function: ShowButton
        function ShowButtonPushed(app, event)
            fColour = str2num(app.FixedColorEditField.Value);
			startC = str2num(app.VarColorStartEditField.Value);
			endC = str2num(app.VarColorEndEditField.Value);
			stepC = str2num(app.VarColorStepsEditField.Value);
			len = 0;
			r = cell(3,1);
			for i = 1:length(r)
				r{i} = linspace(startC(i), endC(i),stepC)';
				if length(r{i}) > len; len = length(r{i}); end
			end
			for i = 1:length(r)
				if isempty(r{i})
					r{i} = zeros(len,1);
				end
			end
			vals = cell(len,1);
			for i = 1:len
				vals{i} = [r{1}(i) r{2}(i) r{3}(i)];
			end
			disp('======Colors======')
			cellfun(@(c) disp(c),vals)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.EquiFlickerTaskUIFigure = uifigure('Visible', 'off');
            app.EquiFlickerTaskUIFigure.Name = 'EquiFlicker Task';
            app.TabGroup = uitabgroup(app.EquiFlickerTaskUIFigure);
            app.SettingsTab = uitab(app.TabGroup);
            app.SettingsTab.Title = 'Settings';
            app.EquiFlickerTaskGeneralPanel = uipanel(app.SettingsTab);
            app.EquiFlickerTaskGeneralPanel.Title = 'EquiFlicker Task: General';
            app.DistancecmEditFieldLabel = uilabel(app.EquiFlickerTaskGeneralPanel);
            app.DistancecmEditFieldLabel.Text = 'Distance (cm)';
            app.DistancecmEditField = uieditfield(app.EquiFlickerTaskGeneralPanel, 'numeric');
            app.DistancecmEditField.Value = 68;
            app.PixelscmEditFieldLabel = uilabel(app.EquiFlickerTaskGeneralPanel);
            app.PixelscmEditFieldLabel.Text = 'Pixels / cm';
            app.PixelscmEditField = uieditfield(app.EquiFlickerTaskGeneralPanel, 'numeric');
            app.PixelscmEditField.Value = 27;
            app.FixationXdegEditFieldLabel = uilabel(app.EquiFlickerTaskGeneralPanel);
            app.FixationXdegEditFieldLabel.Text = 'Fixation X (deg)';
            app.FixationXdegEditField = uieditfield(app.EquiFlickerTaskGeneralPanel, 'numeric');
            app.ELDummySwitchLabel = uilabel(app.EquiFlickerTaskGeneralPanel);
            app.ELDummySwitchLabel.Text = 'EL Dummy';
            app.ELDummySwitch = uiswitch(app.EquiFlickerTaskGeneralPanel, 'rocker');
            app.DebugModeSwitchLabel = uilabel(app.EquiFlickerTaskGeneralPanel);
            app.DebugModeSwitchLabel.Text = 'Debug Mode';
            app.DebugModeSwitch = uiswitch(app.EquiFlickerTaskGeneralPanel, 'rocker');
            app.FixationYdegEditFieldLabel = uilabel(app.EquiFlickerTaskGeneralPanel);
            app.FixationYdegEditFieldLabel.Text = 'Fixation Y (deg)';
            app.FixationYdegEditField = uieditfield(app.EquiFlickerTaskGeneralPanel, 'numeric');
            app.FixInitiationsEditFieldLabel = uilabel(app.EquiFlickerTaskGeneralPanel);
            app.FixInitiationsEditFieldLabel.Text = 'Fix Initiation (s)';
            app.FixInitiationsEditField = uieditfield(app.EquiFlickerTaskGeneralPanel, 'numeric');
            app.FixInitiationsEditField.Value = 1;
            app.Fix1stDurationsEditFieldLabel = uilabel(app.EquiFlickerTaskGeneralPanel);
            app.Fix1stDurationsEditFieldLabel.Text = 'Fix 1st Duration (s)';
            app.Fix1stDurationsEditField = uieditfield(app.EquiFlickerTaskGeneralPanel, 'numeric');
            app.Fix1stDurationsEditField.Limits = [0.1 20];
            app.Fix1stDurationsEditField.Value = 0.5;
            app.FixationDiameterdEditFieldLabel = uilabel(app.EquiFlickerTaskGeneralPanel);
            app.FixationDiameterdEditFieldLabel.Text = 'Fixation Diameter (d)';
            app.FixationDiameterdEditField = uieditfield(app.EquiFlickerTaskGeneralPanel, 'numeric');
            app.FixationDiameterdEditField.Limits = [0.1 20];
            app.FixationDiameterdEditField.Value = 2;
            app.InfoEditFieldLabel = uilabel(app.EquiFlickerTaskGeneralPanel);
            app.InfoEditFieldLabel.Text = 'Info';
            app.InfoEditField = uieditfield(app.EquiFlickerTaskGeneralPanel, 'text');
            app.SubjectEditFieldLabel = uilabel(app.EquiFlickerTaskGeneralPanel);
            app.SubjectEditFieldLabel.Text = 'Subject';
            app.SubjectEditField = uieditfield(app.EquiFlickerTaskGeneralPanel, 'text');
            app.SubjectEditField.Value = 'Joanna Doe';
            app.SavePathEditFieldLabel = uilabel(app.EquiFlickerTaskGeneralPanel);
            app.SavePathEditFieldLabel.Text = 'Save Path';
            app.SavePathEditField = uieditfield(app.EquiFlickerTaskGeneralPanel, 'text');
            app.SavePathEditField.Value = '/home/aristotle/Desktop/IsoLum/';
            app.CalibrationEditFieldLabel = uilabel(app.EquiFlickerTaskGeneralPanel);
            app.CalibrationEditFieldLabel.Text = 'Calibration';
            app.CalibrationEditField = uieditfield(app.EquiFlickerTaskGeneralPanel, 'text');
            app.CButton = uibutton(app.EquiFlickerTaskGeneralPanel, 'push');
            app.CButton.ButtonPushedFcn = createCallbackFcn(app, @CButtonPushed, true);
            app.CButton.Icon = 'go-to-file.png';
            app.CButton.Text = '';
            app.SPButton = uibutton(app.EquiFlickerTaskGeneralPanel, 'push');
            app.SPButton.ButtonPushedFcn = createCallbackFcn(app, @SPButtonPushed, true);
            app.SPButton.Icon = 'go-to-file.png';
            app.SPButton.Text = '';
            app.SendTriggersSwitchLabel = uilabel(app.EquiFlickerTaskGeneralPanel);
            app.SendTriggersSwitchLabel.Text = 'Send Triggers';
            app.SendTriggersSwitch = uiswitch(app.EquiFlickerTaskGeneralPanel, 'rocker');
            app.ColorDepthDropDownLabel = uilabel(app.EquiFlickerTaskGeneralPanel);
            app.ColorDepthDropDownLabel.Text = 'Color Depth';
            app.ColorDepthDropDown = uidropdown(app.EquiFlickerTaskGeneralPanel);
            app.ColorDepthDropDown.Items = {'FloatingPoint32BitIfPossible', 'FloatingPoint32Bit', '8bit', 'EnableBits++Bits++Output', 'EnableBits++Color++Output', 'EnableBits++Mono++Output', 'EnableNative10BitFramebuffer'};
            app.ColorDepthDropDown.Value = 'EnableBits++Color++Output';
            app.EquiFlickerTaskStimulusOptionsPanel = uipanel(app.SettingsTab);
            app.EquiFlickerTaskStimulusOptionsPanel.Title = 'EquiFlicker Task: Stimulus Options';
            app.FixedColorEditFieldLabel = uilabel(app.EquiFlickerTaskStimulusOptionsPanel);
            app.FixedColorEditFieldLabel.Text = 'Fixed Color';
            app.FixedColorEditField = uieditfield(app.EquiFlickerTaskStimulusOptionsPanel, 'text');
            app.FixedColorEditField.Value = '[ 0.75 0 0 ]';
            app.VarColorStartEditFieldLabel = uilabel(app.EquiFlickerTaskStimulusOptionsPanel);
            app.VarColorStartEditFieldLabel.Text = 'Var Color Start';
            app.VarColorStartEditField = uieditfield(app.EquiFlickerTaskStimulusOptionsPanel, 'text');
            app.VarColorStartEditField.Value = '[ 0.5 0 0 ]';
            app.VarColorEndEditFieldLabel = uilabel(app.EquiFlickerTaskStimulusOptionsPanel);
            app.VarColorEndEditFieldLabel.Text = 'Var Color End';
            app.VarColorEndEditField = uieditfield(app.EquiFlickerTaskStimulusOptionsPanel, 'text');
            app.VarColorEndEditField.Value = '[ 1 0 0 ]';
            app.VarColorStepsEditFieldLabel = uilabel(app.EquiFlickerTaskStimulusOptionsPanel);
            app.VarColorStepsEditFieldLabel.Text = 'Var Color Steps';
            app.VarColorStepsEditField = uieditfield(app.EquiFlickerTaskStimulusOptionsPanel, 'text');
            app.VarColorStepsEditField.Value = '9';
            app.SizedLabel = uilabel(app.EquiFlickerTaskStimulusOptionsPanel);
            app.SizedLabel.Text = 'Size (d)';
            app.SizedEditField = uieditfield(app.EquiFlickerTaskStimulusOptionsPanel, 'numeric');
            app.SizedEditField.Limits = [1 100];
            app.SizedEditField.Value = 15;
            app.TRIALSdurationsEditFieldLabel = uilabel(app.EquiFlickerTaskStimulusOptionsPanel);
            app.TRIALSdurationsEditFieldLabel.Text = 'TRIALS:   duration (s)';
            app.TRIALSdurationsEditField = uieditfield(app.EquiFlickerTaskStimulusOptionsPanel, 'numeric');
            app.TRIALSdurationsEditField.Limits = [1 40];
            app.TRIALSdurationsEditField.Tooltip = {'Duration of trial'};
            app.TRIALSdurationsEditField.Value = 1;
            app.BackgroundColorEditFieldLabel = uilabel(app.EquiFlickerTaskStimulusOptionsPanel);
            app.BackgroundColorEditFieldLabel.Text = 'Background Color';
            app.BackgroundColorEditField = uieditfield(app.EquiFlickerTaskStimulusOptionsPanel, 'text');
            app.BackgroundColorEditField.Value = '[ 0.14 0.14 0.14 ]';
            app.FrequencyHzEditFieldLabel = uilabel(app.EquiFlickerTaskStimulusOptionsPanel);
            app.FrequencyHzEditFieldLabel.Text = 'Frequency (Hz)';
            app.FrequencyHzEditField = uieditfield(app.EquiFlickerTaskStimulusOptionsPanel, 'numeric');
            app.FrequencyHzEditField.Limits = [0.1 100];
            app.FrequencyHzEditField.ValueDisplayFormat = '%11.5g';
            app.FrequencyHzEditField.ValueChangedFcn = createCallbackFcn(app, @FrequencyHzEditFieldValueChanged, true);
            app.FrequencyHzEditField.Value = 8;
            app.EditFieldLabel = uilabel(app.EquiFlickerTaskStimulusOptionsPanel);
            app.EditFieldLabel.Text = '#';
            app.EditField = uieditfield(app.EquiFlickerTaskStimulusOptionsPanel, 'numeric');
            app.EditField.Limits = [1 40];
            app.EditField.Tooltip = {'Number of trials'};
            app.EditField.Value = 5;
            app.SpatialFrequencycdEditFieldLabel = uilabel(app.EquiFlickerTaskStimulusOptionsPanel);
            app.SpatialFrequencycdEditFieldLabel.Text = 'Spatial Frequency (c/d)';
            app.SpatialFrequencycdEditField = uieditfield(app.EquiFlickerTaskStimulusOptionsPanel, 'numeric');
            app.SpatialFrequencycdEditField.Limits = [0 50];
            app.SpatialFrequencycdEditField.Value = 0.25;
            app.intervalsEditFieldLabel = uilabel(app.EquiFlickerTaskStimulusOptionsPanel);
            app.intervalsEditFieldLabel.Text = 'interval (s)';
            app.intervalsEditField = uieditfield(app.EquiFlickerTaskStimulusOptionsPanel, 'numeric');
            app.intervalsEditField.Limits = [1 40];
            app.intervalsEditField.Tooltip = {'Interval between trials'};
            app.intervalsEditField.Value = 2;
            app.GratingTypeDropDownLabel = uilabel(app.EquiFlickerTaskStimulusOptionsPanel);
            app.GratingTypeDropDownLabel.Text = 'Grating Type';
            app.GratingTypeDropDown = uidropdown(app.EquiFlickerTaskStimulusOptionsPanel);
            app.GratingTypeDropDown.Items = {'square', 'sinusoid'};
            app.GratingTypeDropDown.Value = 'square';
            app.MaskCheckBox = uicheckbox(app.EquiFlickerTaskStimulusOptionsPanel);
            app.MaskCheckBox.Tooltip = {'Circular mask?'};
            app.MaskCheckBox.Text = 'Mask';
            app.ContrastMultiplierEditFieldLabel = uilabel(app.EquiFlickerTaskStimulusOptionsPanel);
            app.ContrastMultiplierEditFieldLabel.Enable = 'off';
            app.ContrastMultiplierEditFieldLabel.Text = 'Contrast Multiplier';
            app.ContrastMultiplierEditField = uieditfield(app.EquiFlickerTaskStimulusOptionsPanel, 'numeric');
            app.ContrastMultiplierEditField.Limits = [0.1 100];
            app.ContrastMultiplierEditField.ValueDisplayFormat = '%11.5g';
            app.ContrastMultiplierEditField.Enable = 'off';
            app.ContrastMultiplierEditField.Value = 0.2;
            app.DKLCheckBox = uicheckbox(app.EquiFlickerTaskStimulusOptionsPanel);
            app.DKLCheckBox.Tooltip = {'Use DKL colours?'};
            app.DKLCheckBox.Text = 'DKL';
            app.ShowButton = uibutton(app.EquiFlickerTaskStimulusOptionsPanel, 'push');
            app.ShowButton.ButtonPushedFcn = createCallbackFcn(app, @ShowButtonPushed, true);
            app.ShowButton.Text = 'Show';
            app.ControlPanel = uipanel(app.SettingsTab);
            app.ControlPanel.Title = 'Control';
            app.GOButton = uibutton(app.ControlPanel, 'push');
            app.GOButton.ButtonPushedFcn = createCallbackFcn(app, @GOButtonPushed, true);
            app.GOButton.Icon = 'start.png';
            app.GOButton.Text = 'GO!';
            app.SaveButton = uibutton(app.ControlPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Icon = 'gear.png';
            app.SaveButton.Enable = 'off';
            app.SaveButton.Text = 'Save!';
            app.ExitButton = uibutton(app.ControlPanel, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Icon = 'stop.png';
            app.ExitButton.Text = 'Exit!';
            app.ExperimentalRunTab = uitab(app.TabGroup);
            app.ExperimentalRunTab.Title = 'Experimental Run';
            app.EMAxes = uiaxes(app.ExperimentalRunTab);
            title(app.EMAxes, 'Responses')
            xlabel(app.EMAxes, 'Trial #')
            ylabel(app.EMAxes, 'Variable')
            app.EMAxesPower = uiaxes(app.ExperimentalRunTab);
            title(app.EMAxesPower, 'Psychometric Curve')
            xlabel(app.EMAxesPower, 'Variable')
            ylabel(app.EMAxesPower, '% Leftwards')
            app.EMAxesAverage = uiaxes(app.ExperimentalRunTab);
            title(app.EMAxesAverage, 'Proportions')
            xlabel(app.EMAxesAverage, 'Response')
            ylabel(app.EMAxesAverage, '%')
            app.MoreSettingsTab = uitab(app.TabGroup);
            app.MoreSettingsTab.Title = 'More Settings...';
            app.EyelinkPanel = uipanel(app.MoreSettingsTab);
            app.EyelinkPanel.Title = 'Eyelink';
            app.SampleRateKnobLabel = uilabel(app.EyelinkPanel);
            app.SampleRateKnobLabel.Text = 'Sample Rate';
            app.SampleRateKnob = uiknob(app.EyelinkPanel, 'discrete');
            app.SampleRateKnob.Items = {'250', '500', '1000', '2000'};
            app.SampleRateKnob.Value = '1000';
            app.CalibPointsKnobLabel = uilabel(app.EyelinkPanel);
            app.CalibPointsKnobLabel.Text = 'Calib Points';
            app.CalibPointsKnob = uiknob(app.EyelinkPanel, 'discrete');
            app.CalibPointsKnob.Items = {'HV3', 'HV5', 'HV9', 'HV13'};
            app.CalibPointsKnob.Value = 'HV5';
            app.TargetFixationOptionsPanel = uipanel(app.MoreSettingsTab);
            app.TargetFixationOptionsPanel.Title = 'Target Fixation Options';
            app.TargetIntiationEditFieldLabel = uilabel(app.TargetFixationOptionsPanel);
            app.TargetIntiationEditFieldLabel.Enable = 'off';
            app.TargetIntiationEditFieldLabel.Text = 'Target Intiation';
            app.TargetIntiationEditField = uieditfield(app.TargetFixationOptionsPanel, 'numeric');
            app.TargetIntiationEditField.Limits = [0.01 20];
            app.TargetIntiationEditField.Enable = 'off';
            app.TargetIntiationEditField.Value = 0.5;
            app.TargetMaintainEditFieldLabel = uilabel(app.TargetFixationOptionsPanel);
            app.TargetMaintainEditFieldLabel.Enable = 'off';
            app.TargetMaintainEditFieldLabel.Text = 'Target Maintain';
            app.TargetMaintainEditField = uieditfield(app.TargetFixationOptionsPanel, 'numeric');
            app.TargetMaintainEditField.Limits = [0.01 20];
            app.TargetMaintainEditField.Enable = 'off';
            app.TargetMaintainEditField.Value = 0.5;
            app.TargetDiameterEditFieldLabel = uilabel(app.TargetFixationOptionsPanel);
            app.TargetDiameterEditFieldLabel.Enable = 'off';
            app.TargetDiameterEditFieldLabel.Text = 'Target Diameter';
            app.TargetDiameterEditField = uieditfield(app.TargetFixationOptionsPanel, 'numeric');
            app.TargetDiameterEditField.Limits = [0.1 20];
            app.TargetDiameterEditField.Enable = 'off';
            app.TargetDiameterEditField.Value = 6;
            app.OthertrainingparametersPanel = uipanel(app.MoreSettingsTab);
            app.OthertrainingparametersPanel.Title = 'Other training parameters';
            app.InterTrialDelayEditFieldLabel = uilabel(app.OthertrainingparametersPanel);
            app.InterTrialDelayEditFieldLabel.Enable = 'off';
            app.InterTrialDelayEditFieldLabel.Text = 'InterTrial Delay';
            app.InterTrialDelayEditField = uieditfield(app.OthertrainingparametersPanel, 'numeric');
            app.InterTrialDelayEditField.Limits = [0.01 20];
            app.InterTrialDelayEditField.Enable = 'off';
            app.InterTrialDelayEditField.Value = 0.75;
            app.FixOnlyRewardEditFieldLabel = uilabel(app.OthertrainingparametersPanel);
            app.FixOnlyRewardEditFieldLabel.Enable = 'off';
            app.FixOnlyRewardEditFieldLabel.Text = '% Fix Only Reward';
            app.FixOnlyRewardEditField = uieditfield(app.OthertrainingparametersPanel, 'numeric');
            app.FixOnlyRewardEditField.Limits = [0 100];
            app.FixOnlyRewardEditField.RoundFractionalValues = 'on';
            app.FixOnlyRewardEditField.ValueDisplayFormat = '%.0f';
            app.FixOnlyRewardEditField.Enable = 'off';
            app.FixOnlyRewardEditField.Value = 10;
            app.ExclusionZoneEditFieldLabel = uilabel(app.OthertrainingparametersPanel);
            app.ExclusionZoneEditFieldLabel.Enable = 'off';
            app.ExclusionZoneEditFieldLabel.Text = 'Exclusion Zone';
            app.ExclusionZoneEditField = uieditfield(app.OthertrainingparametersPanel, 'text');
            app.ExclusionZoneEditField.Enable = 'off';
            app.ExclusionZoneEditField.Value = '[ ]';
            app.ArduinoPanel = uipanel(app.MoreSettingsTab);
            app.ArduinoPanel.Title = 'Arduino';
            app.SerialPortNameEditFieldLabel = uilabel(app.ArduinoPanel);
            app.SerialPortNameEditFieldLabel.Text = 'Serial Port Name';
            app.SerialPortNameEditField = uieditfield(app.ArduinoPanel, 'text');
            app.SerialPortNameEditField.Value = '/dev/ttyACM1';
            app.SendRewardSwitchLabel = uilabel(app.ArduinoPanel);
            app.SendRewardSwitchLabel.Text = 'Send Reward?';
            app.SendRewardSwitch = uiswitch(app.ArduinoPanel, 'rocker');
            % Show the figure after all components are created
            app.EquiFlickerTaskUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EquiFlicker

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.EquiFlickerTaskUIFigure)

            % Execute the startup function
            runStartupFcn(app, @setPath)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.EquiFlickerTaskUIFigure)
        end
    end
end