classdef IsoLum < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        IsoLuminanceTesterUIFigure      matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        MainSettingsTab                 matlab.ui.container.Tab
        IsoLumPTFMTaskGeneralPanel      matlab.ui.container.Panel
        DistanceEditFieldLabel          matlab.ui.control.Label
        DistanceEditField               matlab.ui.control.NumericEditField
        PixelsPerCmEditFieldLabel       matlab.ui.control.Label
        PixelsPerCmEditField            matlab.ui.control.NumericEditField
        FixationXEditFieldLabel         matlab.ui.control.Label
        FixationXEditField              matlab.ui.control.NumericEditField
        ELDummySwitchLabel              matlab.ui.control.Label
        ELDummySwitch                   matlab.ui.control.RockerSwitch
        DebugModeSwitchLabel            matlab.ui.control.Label
        DebugModeSwitch                 matlab.ui.control.RockerSwitch
        FixationYEditFieldLabel         matlab.ui.control.Label
        FixationYEditField              matlab.ui.control.NumericEditField
        FixInitiationEditFieldLabel     matlab.ui.control.Label
        FixInitiationEditField          matlab.ui.control.NumericEditField
        Fix1stDurationEditFieldLabel    matlab.ui.control.Label
        Fix1stDurationEditField         matlab.ui.control.NumericEditField
        FixationDiameterEditFieldLabel  matlab.ui.control.Label
        FixationDiameterEditField       matlab.ui.control.NumericEditField
        InfoEditFieldLabel              matlab.ui.control.Label
        InfoEditField                   matlab.ui.control.EditField
        SubjectEditFieldLabel           matlab.ui.control.Label
        SubjectEditField                matlab.ui.control.EditField
        SavePathEditFieldLabel          matlab.ui.control.Label
        SavePathEditField               matlab.ui.control.EditField
        CalibrationEditFieldLabel       matlab.ui.control.Label
        CalibrationEditField            matlab.ui.control.EditField
        CButton                         matlab.ui.control.Button
        SPButton                        matlab.ui.control.Button
        SendTriggersSwitchLabel         matlab.ui.control.Label
        SendTriggersSwitch              matlab.ui.control.RockerSwitch
        ColorDepthDropDownLabel         matlab.ui.control.Label
        ColorDepthDropDown              matlab.ui.control.DropDown
        IsoLumPTFMTaskStimulusOptionsPanel  matlab.ui.container.Panel
        FixedColorEditFieldLabel        matlab.ui.control.Label
        FixedColorEditField             matlab.ui.control.EditField
        VarColorStartEditFieldLabel     matlab.ui.control.Label
        VarColorStartEditField          matlab.ui.control.EditField
        VarColorEndEditFieldLabel       matlab.ui.control.Label
        VarColorEndEditField            matlab.ui.control.EditField
        VarColorStepsEditFieldLabel     matlab.ui.control.Label
        VarColorStepsEditField          matlab.ui.control.EditField
        Circle1DiameterEditFieldLabel   matlab.ui.control.Label
        Circle1DiameterEditField        matlab.ui.control.NumericEditField
        TRIALSdurationsEditFieldLabel   matlab.ui.control.Label
        TRIALSdurationsEditField        matlab.ui.control.NumericEditField
        BackgroundColorEditFieldLabel   matlab.ui.control.Label
        BackgroundColorEditField        matlab.ui.control.EditField
        FrequencyEditFieldLabel         matlab.ui.control.Label
        FrequencyEditField              matlab.ui.control.NumericEditField
        EditFieldLabel                  matlab.ui.control.Label
        EditField                       matlab.ui.control.NumericEditField
        Circle2DiameterEditFieldLabel   matlab.ui.control.Label
        Circle2DiameterEditField        matlab.ui.control.NumericEditField
        sigma1                          matlab.ui.control.NumericEditField
        sigma2                          matlab.ui.control.NumericEditField
        intervalsEditFieldLabel         matlab.ui.control.Label
        intervalsEditField              matlab.ui.control.NumericEditField
        DKLCheckBox                     matlab.ui.control.CheckBox
        ShowButton                      matlab.ui.control.Button
        f120HzLabel                     matlab.ui.control.Label
        ControlPanel                    matlab.ui.container.Panel
        GOButton                        matlab.ui.control.Button
        SaveButton                      matlab.ui.control.Button
        ExitButton                      matlab.ui.control.Button
        AdditionalSettingsTab           matlab.ui.container.Tab
        EyelinkPanel                    matlab.ui.container.Panel
        SampleRateKnobLabel             matlab.ui.control.Label
        SampleRateKnob                  matlab.ui.control.DiscreteKnob
        CalibPointsKnobLabel            matlab.ui.control.Label
        CalibPointsKnob                 matlab.ui.control.DiscreteKnob
        ManualCalibrationCheckBox       matlab.ui.control.CheckBox
        FixationColourEditFieldLabel    matlab.ui.control.Label
        FixationColourEditField         matlab.ui.control.EditField
        CrossCheckBox                   matlab.ui.control.CheckBox
        TargetFixationOptionsPanel      matlab.ui.container.Panel
        TargetIntiationEditFieldLabel   matlab.ui.control.Label
        TargetIntiationEditField        matlab.ui.control.NumericEditField
        TargetMaintainEditFieldLabel    matlab.ui.control.Label
        TargetMaintainEditField         matlab.ui.control.NumericEditField
        TargetDiameterEditFieldLabel    matlab.ui.control.Label
        TargetDiameterEditField         matlab.ui.control.NumericEditField
        OthertrainingparametersPanel    matlab.ui.container.Panel
        InterTrialDelayEditFieldLabel   matlab.ui.control.Label
        InterTrialDelayEditField        matlab.ui.control.NumericEditField
        FixOnlyRewardEditFieldLabel     matlab.ui.control.Label
        FixOnlyRewardEditField          matlab.ui.control.NumericEditField
        ExclusionZoneEditFieldLabel     matlab.ui.control.Label
        ExclusionZoneEditField          matlab.ui.control.EditField
        ArduinoPanel                    matlab.ui.container.Panel
        SerialPortNameEditFieldLabel    matlab.ui.control.Label
        SerialPortNameEditField         matlab.ui.control.EditField
        SendRewardSwitchLabel           matlab.ui.control.Label
        SendRewardSwitch                matlab.ui.control.RockerSwitch
        RewardAmountmsEditFieldLabel    matlab.ui.control.Label
        RewardAmountmsEditField         matlab.ui.control.NumericEditField
        ExperimentPlotsTab              matlab.ui.container.Tab
        IsoLumAxes                      matlab.ui.control.UIAxes
        IsoLumAxesPower                 matlab.ui.control.UIAxes
        IsoLumAxesAverage               matlab.ui.control.UIAxes
    end

 
 properties (Access = public)
  ana % parsed settings
 end
 
 methods (Access = private)
  
  function parseSettings(app)
       app.ana = [];
       app.ana.uuid = num2str(dec2hex(floor((now - floor(now))*1e10)));
       app.ana.subject = app.SubjectEditField.Value;
       app.ana.comments = app.InfoEditField.Value;
       app.ana.ResultDir = app.SavePathEditField.Value;
       app.ana.gammaTable = app.CalibrationEditField.Value;
       app.ana.pixelsPerCm = app.PixelsPerCmEditField.Value;
       app.ana.distance = app.DistanceEditField.Value;
       app.ana.bitDepth = app.ColorDepthDropDown.Value;
       app.ana.fixX = app.FixationXEditField.Value;
       app.ana.fixY = app.FixationYEditField.Value;
       app.ana.firstFixInit = app.FixInitiationEditField.Value;
       app.ana.firstFixDiameter = app.FixationDiameterEditField.Value;
       app.ana.firstFixTime = app.Fix1stDurationEditField.Value;
       app.ana.backgroundColor = str2num(app.BackgroundColorEditField.Value);
       app.ana.useDKL = logical(app.DKLCheckBox.Value);
       app.ana.colorFixed = str2num(app.FixedColorEditField.Value);
       app.ana.colorStart = str2num(app.VarColorStartEditField.Value);
       app.ana.colorEnd = str2num(app.VarColorEndEditField.Value);
       app.ana.colorStep = str2num(app.VarColorStepsEditField.Value);
       app.ana.circleDiameter = app.Circle1DiameterEditField.Value;
       app.ana.backgroundDiameter = app.Circle2DiameterEditField.Value;
       app.ana.sigma1 = app.sigma1.Value;
       app.ana.sigma2 = app.sigma2.Value;
       app.ana.trialDuration = app.TRIALSdurationsEditField.Value;
       app.ana.trialInterval = app.intervalsEditField.Value;
       app.ana.trialNumber = app.EditField.Value;
       app.ana.frequency = app.FrequencyEditField.Value;
       app.ana.sampleRate = str2num(app.SampleRateKnob.Value);
       app.ana.fixColour = str2num(app.FixationColourEditField.Value);
       app.ana.fixManual = logical(app.ManualCalibrationCheckBox.Value);
       app.ana.fixCross = logical(app.CrossCheckBox.Value);
       app.ana.calibrationStyle = app.CalibPointsKnob.Value;
       app.ana.arduinoPort = app.SerialPortNameEditField.Value;
       app.ana.rewardTime = app.RewardAmountmsEditField.Value;
       
       if strcmpi(app.DebugModeSwitch.Value,'On')
        app.ana.debug = true;
        app.ana.windowed = [0 0 1000 1000];
       else
        app.ana.debug = false;
        app.ana.windowed = [];
       end
       if strcmpi(app.ELDummySwitch.Value,'On')
        app.ana.isDummy = true;
       else
        app.ana.isDummy = false;
       end
       if strcmpi(app.SendTriggersSwitch.Value,'On')
           app.ana.sendTrigger = true;
       else
           app.ana.sendTrigger = false;
       end
       if strcmpi(app.SendRewardSwitch.Value,'On')
           app.ana.sendReward = true;
       else
           app.ana.sendReward = false;
       end
       app.ana.plotAxis1 = app.IsoLumAxes;
       app.ana.plotAxis2 = app.IsoLumAxesPower;
       app.ana.plotAxis3 = app.IsoLumAxesAverage;
  end

 end
 

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function setPath(app)
            s=screenManager();
            app.SavePathEditField.Value=s.paths.savedData;
            if IsLinux
                app.SerialPortNameEditField.Value = '/dev/ttyUSB0';
            end
        end

        % Button pushed function: CButton
        function CButtonPushed(app, event)
            
            [f, p] = uigetfile('*.mat','Select a Calibration File');
            app.CalibrationEditField.Value = [p f];
        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
           delete(app);
        end

        % Button pushed function: GOButton
        function GOButtonPushed(app, event)
           event.Source.BusyAction = 'cancel';
           event.Source.Interruptible = 'off';
           app.parseSettings();
           fprintf('\n=========GO BUTTON PUSHED============\n')
           runIsoluminant(app.ana)
        end

        % Button pushed function: SPButton
        function SPButtonPushed(app, event)
            app.setPath();
            cd(app.SavePathEditField.Value);
           app.SavePathEditField.Value = uigetdir;
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
           app.parseSettings();
        end

        % Value changed function: FrequencyEditField
        function FrequencyEditFieldValueChanged(app, event)
            value = app.FrequencyEditField.Value;
			f = round(((1/value) * 120) / 2);
			app.f120HzLabel.Text = [num2str(f) 'f @ 120Hz'];
        end

        % Button pushed function: ShowButton
        function ShowButtonPushed(app, event)
			fColour = str2num(app.FixedColorEditField.Value);
			startC = str2num(app.VarColorStartEditField.Value);
			endC = str2num(app.VarColorEndEditField.Value);
			stepC = str2num(app.VarColorStepsEditField.Value);
			len = 0;
			r = cell(3,1);
			for i = 1:length(r)
				r{i} = linspace(startC(i), endC(i),stepC)';
				if length(r{i}) > len; len = length(r{i}); end
			end
			for i = 1:length(r)
				if isempty(r{i})
					r{i} = zeros(len,1);
				end
			end
			vals = cell(len,1);
			for i = 1:len
				vals{i} = [r{1}(i) r{2}(i) r{3}(i)];
			end
			disp('======Colors======')
			cellfun(@(c) disp(c),vals)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.IsoLuminanceTesterUIFigure = uifigure('Visible', 'off');
            app.IsoLuminanceTesterUIFigure.Name = 'IsoLuminance Tester';
            app.TabGroup = uitabgroup(app.IsoLuminanceTesterUIFigure);
            app.MainSettingsTab = uitab(app.TabGroup);
            app.MainSettingsTab.Title = 'Main Settings';
            app.IsoLumPTFMTaskGeneralPanel = uipanel(app.MainSettingsTab);
            app.IsoLumPTFMTaskGeneralPanel.Title = 'IsoLum PTFM Task: General';
            app.DistanceEditFieldLabel = uilabel(app.IsoLumPTFMTaskGeneralPanel);
            app.DistanceEditFieldLabel.Text = 'Distance';
            app.DistanceEditField = uieditfield(app.IsoLumPTFMTaskGeneralPanel, 'numeric');
            app.DistanceEditField.Value = 68;
            app.PixelsPerCmEditFieldLabel = uilabel(app.IsoLumPTFMTaskGeneralPanel);
            app.PixelsPerCmEditFieldLabel.Text = 'PixelsPerCm';
            app.PixelsPerCmEditField = uieditfield(app.IsoLumPTFMTaskGeneralPanel, 'numeric');
            app.PixelsPerCmEditField.Value = 27;
            app.FixationXEditFieldLabel = uilabel(app.IsoLumPTFMTaskGeneralPanel);
            app.FixationXEditFieldLabel.Text = 'Fixation X';
            app.FixationXEditField = uieditfield(app.IsoLumPTFMTaskGeneralPanel, 'numeric');
            app.ELDummySwitchLabel = uilabel(app.IsoLumPTFMTaskGeneralPanel);
            app.ELDummySwitchLabel.Text = 'EL Dummy';
            app.ELDummySwitch = uiswitch(app.IsoLumPTFMTaskGeneralPanel, 'rocker');
            app.DebugModeSwitchLabel = uilabel(app.IsoLumPTFMTaskGeneralPanel);
            app.DebugModeSwitchLabel.Text = 'Debug Mode';
            app.DebugModeSwitch = uiswitch(app.IsoLumPTFMTaskGeneralPanel, 'rocker');
            app.FixationYEditFieldLabel = uilabel(app.IsoLumPTFMTaskGeneralPanel);
            app.FixationYEditFieldLabel.Text = 'Fixation Y';
            app.FixationYEditField = uieditfield(app.IsoLumPTFMTaskGeneralPanel, 'numeric');
            app.FixInitiationEditFieldLabel = uilabel(app.IsoLumPTFMTaskGeneralPanel);
            app.FixInitiationEditFieldLabel.Text = 'Fix Initiation';
            app.FixInitiationEditField = uieditfield(app.IsoLumPTFMTaskGeneralPanel, 'numeric');
            app.FixInitiationEditField.Value = 1;
            app.Fix1stDurationEditFieldLabel = uilabel(app.IsoLumPTFMTaskGeneralPanel);
            app.Fix1stDurationEditFieldLabel.Text = 'Fix 1st Duration';
            app.Fix1stDurationEditField = uieditfield(app.IsoLumPTFMTaskGeneralPanel, 'numeric');
            app.Fix1stDurationEditField.Limits = [0.1 20];
            app.Fix1stDurationEditField.Value = 1;
            app.FixationDiameterEditFieldLabel = uilabel(app.IsoLumPTFMTaskGeneralPanel);
            app.FixationDiameterEditFieldLabel.Text = 'Fixation Diameter';
            app.FixationDiameterEditField = uieditfield(app.IsoLumPTFMTaskGeneralPanel, 'numeric');
            app.FixationDiameterEditField.Limits = [0.1 20];
            app.FixationDiameterEditField.Value = 2;
            app.InfoEditFieldLabel = uilabel(app.IsoLumPTFMTaskGeneralPanel);
            app.InfoEditFieldLabel.Text = 'Info';
            app.InfoEditField = uieditfield(app.IsoLumPTFMTaskGeneralPanel, 'text');
            app.SubjectEditFieldLabel = uilabel(app.IsoLumPTFMTaskGeneralPanel);
            app.SubjectEditFieldLabel.Text = 'Subject';
            app.SubjectEditField = uieditfield(app.IsoLumPTFMTaskGeneralPanel, 'text');
            app.SubjectEditField.Value = 'Joanna Doe';
            app.SavePathEditFieldLabel = uilabel(app.IsoLumPTFMTaskGeneralPanel);
            app.SavePathEditFieldLabel.Text = 'Save Path';
            app.SavePathEditField = uieditfield(app.IsoLumPTFMTaskGeneralPanel, 'text');
            app.SavePathEditField.Value = '/home/aristotle/Desktop/IsoLum/';
            app.CalibrationEditFieldLabel = uilabel(app.IsoLumPTFMTaskGeneralPanel);
            app.CalibrationEditFieldLabel.Text = 'Calibration';
            app.CalibrationEditField = uieditfield(app.IsoLumPTFMTaskGeneralPanel, 'text');
            app.CButton = uibutton(app.IsoLumPTFMTaskGeneralPanel, 'push');
            app.CButton.ButtonPushedFcn = createCallbackFcn(app, @CButtonPushed, true);
            app.CButton.Icon = 'go-to-file.png';
            app.CButton.Text = '';
            app.SPButton = uibutton(app.IsoLumPTFMTaskGeneralPanel, 'push');
            app.SPButton.ButtonPushedFcn = createCallbackFcn(app, @SPButtonPushed, true);
            app.SPButton.Icon = 'go-to-file.png';
            app.SPButton.Text = '';
            app.SendTriggersSwitchLabel = uilabel(app.IsoLumPTFMTaskGeneralPanel);
            app.SendTriggersSwitchLabel.Text = 'Send Triggers';
            app.SendTriggersSwitch = uiswitch(app.IsoLumPTFMTaskGeneralPanel, 'rocker');
            app.ColorDepthDropDownLabel = uilabel(app.IsoLumPTFMTaskGeneralPanel);
            app.ColorDepthDropDownLabel.Text = 'Color Depth';
            app.ColorDepthDropDown = uidropdown(app.IsoLumPTFMTaskGeneralPanel);
            app.ColorDepthDropDown.Items = {'FloatingPoint32BitIfPossible', 'FloatingPoint32Bit', '8bit', 'EnableBits++Bits++Output', 'EnableBits++Color++Output', 'EnableBits++Mono++Output', 'EnableNative10BitFramebuffer'};
            app.ColorDepthDropDown.Value = 'EnableBits++Color++Output';
            app.IsoLumPTFMTaskStimulusOptionsPanel = uipanel(app.MainSettingsTab);
            app.IsoLumPTFMTaskStimulusOptionsPanel.Title = 'IsoLum PTFM Task: Stimulus Options';
            app.FixedColorEditFieldLabel = uilabel(app.IsoLumPTFMTaskStimulusOptionsPanel);
            app.FixedColorEditFieldLabel.Text = 'Fixed Color';
            app.FixedColorEditField = uieditfield(app.IsoLumPTFMTaskStimulusOptionsPanel, 'text');
            app.FixedColorEditField.Value = '[ 0.75 0 0 ]';
            app.VarColorStartEditFieldLabel = uilabel(app.IsoLumPTFMTaskStimulusOptionsPanel);
            app.VarColorStartEditFieldLabel.Text = 'Var Color Start';
            app.VarColorStartEditField = uieditfield(app.IsoLumPTFMTaskStimulusOptionsPanel, 'text');
            app.VarColorStartEditField.Value = '[ 0 0 0 ]';
            app.VarColorEndEditFieldLabel = uilabel(app.IsoLumPTFMTaskStimulusOptionsPanel);
            app.VarColorEndEditFieldLabel.Text = 'Var Color End';
            app.VarColorEndEditField = uieditfield(app.IsoLumPTFMTaskStimulusOptionsPanel, 'text');
            app.VarColorEndEditField.Value = '[ 0 0.5 0 ]';
            app.VarColorStepsEditFieldLabel = uilabel(app.IsoLumPTFMTaskStimulusOptionsPanel);
            app.VarColorStepsEditFieldLabel.Text = 'Var Color Steps';
            app.VarColorStepsEditField = uieditfield(app.IsoLumPTFMTaskStimulusOptionsPanel, 'text');
            app.VarColorStepsEditField.Value = '10';
            app.Circle1DiameterEditFieldLabel = uilabel(app.IsoLumPTFMTaskStimulusOptionsPanel);
            app.Circle1DiameterEditFieldLabel.Text = 'Circle 1 Diameter';
            app.Circle1DiameterEditField = uieditfield(app.IsoLumPTFMTaskStimulusOptionsPanel, 'numeric');
            app.Circle1DiameterEditField.Limits = [1 100];
            app.Circle1DiameterEditField.Value = 20;
            app.TRIALSdurationsEditFieldLabel = uilabel(app.IsoLumPTFMTaskStimulusOptionsPanel);
            app.TRIALSdurationsEditFieldLabel.Text = 'TRIALS:    duration (s)';
            app.TRIALSdurationsEditField = uieditfield(app.IsoLumPTFMTaskStimulusOptionsPanel, 'numeric');
            app.TRIALSdurationsEditField.Limits = [1 40];
            app.TRIALSdurationsEditField.Value = 3.5;
            app.BackgroundColorEditFieldLabel = uilabel(app.IsoLumPTFMTaskStimulusOptionsPanel);
            app.BackgroundColorEditFieldLabel.Text = 'Background Color';
            app.BackgroundColorEditField = uieditfield(app.IsoLumPTFMTaskStimulusOptionsPanel, 'text');
            app.BackgroundColorEditField.Value = '[ 0.75 0 0 ]';
            app.FrequencyEditFieldLabel = uilabel(app.IsoLumPTFMTaskStimulusOptionsPanel);
            app.FrequencyEditFieldLabel.Text = 'Frequency';
            app.FrequencyEditField = uieditfield(app.IsoLumPTFMTaskStimulusOptionsPanel, 'numeric');
            app.FrequencyEditField.Limits = [0 40];
            app.FrequencyEditField.ValueDisplayFormat = '%11.5g';
            app.FrequencyEditField.ValueChangedFcn = createCallbackFcn(app, @FrequencyEditFieldValueChanged, true);
            app.FrequencyEditField.Value = 1.7647;
            app.EditFieldLabel = uilabel(app.IsoLumPTFMTaskStimulusOptionsPanel);
            app.EditFieldLabel.Text = '#';
            app.EditField = uieditfield(app.IsoLumPTFMTaskStimulusOptionsPanel, 'numeric');
            app.EditField.Limits = [1 40];
            app.EditField.Value = 5;
            app.Circle2DiameterEditFieldLabel = uilabel(app.IsoLumPTFMTaskStimulusOptionsPanel);
            app.Circle2DiameterEditFieldLabel.Text = 'Circle 2 Diameter';
            app.Circle2DiameterEditField = uieditfield(app.IsoLumPTFMTaskStimulusOptionsPanel, 'numeric');
            app.Circle2DiameterEditField.Limits = [1 100];
            app.Circle2DiameterEditField.Value = 2;
            app.sigma1 = uieditfield(app.IsoLumPTFMTaskStimulusOptionsPanel, 'numeric');
            app.sigma1.Limits = [0 501];
            app.sigma1.Value = 11;
            app.sigma2 = uieditfield(app.IsoLumPTFMTaskStimulusOptionsPanel, 'numeric');
            app.sigma2.Limits = [0 501];
            app.sigma2.Value = 11;
            app.intervalsEditFieldLabel = uilabel(app.IsoLumPTFMTaskStimulusOptionsPanel);
            app.intervalsEditFieldLabel.Text = 'interval (s)';
            app.intervalsEditField = uieditfield(app.IsoLumPTFMTaskStimulusOptionsPanel, 'numeric');
            app.intervalsEditField.Limits = [1 40];
            app.intervalsEditField.Value = 3;
            app.DKLCheckBox = uicheckbox(app.IsoLumPTFMTaskStimulusOptionsPanel);
            app.DKLCheckBox.Text = 'DKL';
            app.ShowButton = uibutton(app.IsoLumPTFMTaskStimulusOptionsPanel, 'push');
            app.ShowButton.ButtonPushedFcn = createCallbackFcn(app, @ShowButtonPushed, true);
            app.ShowButton.Text = 'Show';
            app.f120HzLabel = uilabel(app.IsoLumPTFMTaskStimulusOptionsPanel);
            app.f120HzLabel.Text = '34f @ 120Hz';
            app.ControlPanel = uipanel(app.MainSettingsTab);
            app.ControlPanel.Title = 'Control';
            app.GOButton = uibutton(app.ControlPanel, 'push');
            app.GOButton.ButtonPushedFcn = createCallbackFcn(app, @GOButtonPushed, true);
            app.GOButton.Icon = 'start.png';
            app.GOButton.Text = 'GO!';
            app.SaveButton = uibutton(app.ControlPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Enable = 'off';
            app.SaveButton.Text = 'Save!';
            app.ExitButton = uibutton(app.ControlPanel, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Icon = 'stop.png';
            app.ExitButton.Text = 'Exit!';
            app.AdditionalSettingsTab = uitab(app.TabGroup);
            app.AdditionalSettingsTab.Title = 'Additional Settings...';
            app.EyelinkPanel = uipanel(app.AdditionalSettingsTab);
            app.EyelinkPanel.Title = 'Eyelink';
            app.SampleRateKnobLabel = uilabel(app.EyelinkPanel);
            app.SampleRateKnobLabel.Text = 'Sample Rate';
            app.SampleRateKnob = uiknob(app.EyelinkPanel, 'discrete');
            app.SampleRateKnob.Items = {'250', '500', '1000', '2000'};
            app.SampleRateKnob.Value = '1000';
            app.CalibPointsKnobLabel = uilabel(app.EyelinkPanel);
            app.CalibPointsKnobLabel.Text = 'Calib Points';
            app.CalibPointsKnob = uiknob(app.EyelinkPanel, 'discrete');
            app.CalibPointsKnob.Items = {'HV3', 'HV5', 'HV9', 'HV13'};
            app.CalibPointsKnob.Value = 'HV5';
            app.ManualCalibrationCheckBox = uicheckbox(app.EyelinkPanel);
            app.ManualCalibrationCheckBox.Text = 'Manual Calibration?';
            app.FixationColourEditFieldLabel = uilabel(app.EyelinkPanel);
            app.FixationColourEditFieldLabel.Text = 'Fixation Colour';
            app.FixationColourEditField = uieditfield(app.EyelinkPanel, 'text');
            app.FixationColourEditField.Value = '0.5 0.5 0.5';
            app.CrossCheckBox = uicheckbox(app.EyelinkPanel);
            app.CrossCheckBox.Text = 'Cross';
            app.CrossCheckBox.Value = true;
            app.TargetFixationOptionsPanel = uipanel(app.AdditionalSettingsTab);
            app.TargetFixationOptionsPanel.Title = 'Target Fixation Options';
            app.TargetIntiationEditFieldLabel = uilabel(app.TargetFixationOptionsPanel);
            app.TargetIntiationEditFieldLabel.Enable = 'off';
            app.TargetIntiationEditFieldLabel.Text = 'Target Intiation';
            app.TargetIntiationEditField = uieditfield(app.TargetFixationOptionsPanel, 'numeric');
            app.TargetIntiationEditField.Limits = [0.01 20];
            app.TargetIntiationEditField.Enable = 'off';
            app.TargetIntiationEditField.Value = 0.5;
            app.TargetMaintainEditFieldLabel = uilabel(app.TargetFixationOptionsPanel);
            app.TargetMaintainEditFieldLabel.Enable = 'off';
            app.TargetMaintainEditFieldLabel.Text = 'Target Maintain';
            app.TargetMaintainEditField = uieditfield(app.TargetFixationOptionsPanel, 'numeric');
            app.TargetMaintainEditField.Limits = [0.01 20];
            app.TargetMaintainEditField.Enable = 'off';
            app.TargetMaintainEditField.Value = 0.5;
            app.TargetDiameterEditFieldLabel = uilabel(app.TargetFixationOptionsPanel);
            app.TargetDiameterEditFieldLabel.Enable = 'off';
            app.TargetDiameterEditFieldLabel.Text = 'Target Diameter';
            app.TargetDiameterEditField = uieditfield(app.TargetFixationOptionsPanel, 'numeric');
            app.TargetDiameterEditField.Limits = [0.1 20];
            app.TargetDiameterEditField.Enable = 'off';
            app.TargetDiameterEditField.Value = 6;
            app.OthertrainingparametersPanel = uipanel(app.AdditionalSettingsTab);
            app.OthertrainingparametersPanel.Title = 'Other training parameters';
            app.InterTrialDelayEditFieldLabel = uilabel(app.OthertrainingparametersPanel);
            app.InterTrialDelayEditFieldLabel.Enable = 'off';
            app.InterTrialDelayEditFieldLabel.Text = 'InterTrial Delay';
            app.InterTrialDelayEditField = uieditfield(app.OthertrainingparametersPanel, 'numeric');
            app.InterTrialDelayEditField.Limits = [0.01 20];
            app.InterTrialDelayEditField.Enable = 'off';
            app.InterTrialDelayEditField.Value = 0.75;
            app.FixOnlyRewardEditFieldLabel = uilabel(app.OthertrainingparametersPanel);
            app.FixOnlyRewardEditFieldLabel.Enable = 'off';
            app.FixOnlyRewardEditFieldLabel.Text = '% Fix Only Reward';
            app.FixOnlyRewardEditField = uieditfield(app.OthertrainingparametersPanel, 'numeric');
            app.FixOnlyRewardEditField.Limits = [0 100];
            app.FixOnlyRewardEditField.RoundFractionalValues = 'on';
            app.FixOnlyRewardEditField.ValueDisplayFormat = '%.0f';
            app.FixOnlyRewardEditField.Enable = 'off';
            app.FixOnlyRewardEditField.Value = 10;
            app.ExclusionZoneEditFieldLabel = uilabel(app.OthertrainingparametersPanel);
            app.ExclusionZoneEditFieldLabel.Enable = 'off';
            app.ExclusionZoneEditFieldLabel.Text = 'Exclusion Zone';
            app.ExclusionZoneEditField = uieditfield(app.OthertrainingparametersPanel, 'text');
            app.ExclusionZoneEditField.Enable = 'off';
            app.ExclusionZoneEditField.Value = '[ ]';
            app.ArduinoPanel = uipanel(app.AdditionalSettingsTab);
            app.ArduinoPanel.Title = 'Arduino';
            app.SerialPortNameEditFieldLabel = uilabel(app.ArduinoPanel);
            app.SerialPortNameEditFieldLabel.Text = 'Serial Port Name';
            app.SerialPortNameEditField = uieditfield(app.ArduinoPanel, 'text');
            app.SerialPortNameEditField.Value = 'COM4';
            app.SendRewardSwitchLabel = uilabel(app.ArduinoPanel);
            app.SendRewardSwitchLabel.Text = 'Send Reward?';
            app.SendRewardSwitch = uiswitch(app.ArduinoPanel, 'rocker');
            app.RewardAmountmsEditFieldLabel = uilabel(app.ArduinoPanel);
            app.RewardAmountmsEditFieldLabel.Text = 'Reward Amount (ms)';
            app.RewardAmountmsEditField = uieditfield(app.ArduinoPanel, 'numeric');
            app.RewardAmountmsEditField.Limits = [0 1000];
            app.RewardAmountmsEditField.RoundFractionalValues = 'on';
            app.RewardAmountmsEditField.Value = 150;
            app.ExperimentPlotsTab = uitab(app.TabGroup);
            app.ExperimentPlotsTab.Title = 'Experiment Plots';
            app.IsoLumAxes = uiaxes(app.ExperimentPlotsTab);
            title(app.IsoLumAxes, 'Raw Pupil Data')
            xlabel(app.IsoLumAxes, 'Time (s)')
            ylabel(app.IsoLumAxes, 'Pupil Diameter')
            app.IsoLumAxesPower = uiaxes(app.ExperimentPlotsTab);
            title(app.IsoLumAxesPower, 'Power Per Trial')
            xlabel(app.IsoLumAxesPower, 'Trial')
            ylabel(app.IsoLumAxesPower, 'Power')
            app.IsoLumAxesAverage = uiaxes(app.ExperimentPlotsTab);
            title(app.IsoLumAxesAverage, 'Power per Variable')
            xlabel(app.IsoLumAxesAverage, 'Variable')
            ylabel(app.IsoLumAxesAverage, 'Power')
            % Show the figure after all components are created
            app.IsoLuminanceTesterUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = IsoLum

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.IsoLuminanceTesterUIFigure)

            % Execute the startup function
            runStartupFcn(app, @setPath)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.IsoLuminanceTesterUIFigure)
        end
    end
end