classdef trainColour < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TrainColourTestUIFigure         matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        SettingsTab                     matlab.ui.container.Tab
        ControlPanel                    matlab.ui.container.Panel
        ExitButton                      matlab.ui.control.Button
        SaveButton                      matlab.ui.control.Button
        GOButton                        matlab.ui.control.Button
        StimulusOptionsPanel            matlab.ui.container.Panel
        Contrast3EditField              matlab.ui.control.NumericEditField
        Contrast3EditFieldLabel         matlab.ui.control.Label
        RadiusDivisor                   matlab.ui.control.NumericEditField
        DKLCheckBox                     matlab.ui.control.CheckBox
        PunishTimeEditField             matlab.ui.control.NumericEditField
        PunishTimeEditFieldLabel        matlab.ui.control.Label
        Contrast2EditField              matlab.ui.control.NumericEditField
        Contrast2EditFieldLabel         matlab.ui.control.Label
        DelayToChoiceEditField          matlab.ui.control.EditField
        DelayToChoiceEditFieldLabel     matlab.ui.control.Label
        sigma2                          matlab.ui.control.NumericEditField
        sigma1                          matlab.ui.control.NumericEditField
        Circle2DiameterEditField        matlab.ui.control.NumericEditField
        Circle2DiameterEditFieldLabel   matlab.ui.control.Label
        TrialBlocksEditField            matlab.ui.control.NumericEditField
        TrialBlocksEditFieldLabel       matlab.ui.control.Label
        Contrast1EditField              matlab.ui.control.NumericEditField
        Contrast1EditFieldLabel         matlab.ui.control.Label
        BackgroundColorEditField        matlab.ui.control.EditField
        BackgroundColorEditFieldLabel   matlab.ui.control.Label
        Circle1DiameterEditField        matlab.ui.control.NumericEditField
        Circle1DiameterEditFieldLabel   matlab.ui.control.Label
        PositionXYEditField             matlab.ui.control.EditField
        PositionXYEditFieldLabel        matlab.ui.control.Label
        Color2EditField                 matlab.ui.control.EditField
        Color2EditFieldLabel            matlab.ui.control.Label
        Color1EditField                 matlab.ui.control.EditField
        Color1EditFieldLabel            matlab.ui.control.Label
        GeneralPanel                    matlab.ui.container.Panel
        SendTriggersSwitch              matlab.ui.control.RockerSwitch
        SendTriggersSwitchLabel         matlab.ui.control.Label
        RewardmsEditField               matlab.ui.control.NumericEditField
        RewardmsEditFieldLabel          matlab.ui.control.Label
        SPButton                        matlab.ui.control.Button
        CButton                         matlab.ui.control.Button
        CalibrationEditField            matlab.ui.control.EditField
        CalibrationEditFieldLabel       matlab.ui.control.Label
        SavePathEditField               matlab.ui.control.EditField
        SavePathEditFieldLabel          matlab.ui.control.Label
        SubjectEditField                matlab.ui.control.EditField
        SubjectEditFieldLabel           matlab.ui.control.Label
        InfoEditField                   matlab.ui.control.EditField
        InfoEditFieldLabel              matlab.ui.control.Label
        FixationDiameterEditField       matlab.ui.control.NumericEditField
        FixationDiameterEditFieldLabel  matlab.ui.control.Label
        Fix1stDurationEditField         matlab.ui.control.NumericEditField
        Fix1stDurationEditFieldLabel    matlab.ui.control.Label
        FixInitiationEditField          matlab.ui.control.NumericEditField
        FixInitiationEditFieldLabel     matlab.ui.control.Label
        FixationYEditField              matlab.ui.control.NumericEditField
        FixationYEditFieldLabel         matlab.ui.control.Label
        DebugModeSwitch                 matlab.ui.control.RockerSwitch
        DebugModeSwitchLabel            matlab.ui.control.Label
        ELDummySwitch                   matlab.ui.control.RockerSwitch
        ELDummySwitchLabel              matlab.ui.control.Label
        FixationXEditField              matlab.ui.control.NumericEditField
        FixationXEditFieldLabel         matlab.ui.control.Label
        PixelsPerCmEditField            matlab.ui.control.NumericEditField
        PixelsPerCmEditFieldLabel       matlab.ui.control.Label
        DistanceEditField               matlab.ui.control.NumericEditField
        DistanceEditFieldLabel          matlab.ui.control.Label
        ExperimentalRunTab              matlab.ui.container.Tab
        TrainAxesPerf2                  matlab.ui.control.UIAxes
        TrainAxesPerf                   matlab.ui.control.UIAxes
        TrainAxes                       matlab.ui.control.UIAxes
        MoreSettingsTab                 matlab.ui.container.Tab
        ArduinoPanel                    matlab.ui.container.Panel
        SerialPortNameEditField         matlab.ui.control.EditField
        SerialPortNameEditFieldLabel    matlab.ui.control.Label
        OthertrainingparametersPanel    matlab.ui.container.Panel
        ExclusionZoneEditField          matlab.ui.control.EditField
        ExclusionZoneEditFieldLabel     matlab.ui.control.Label
        FixOnlyRewardEditField          matlab.ui.control.NumericEditField
        FixOnlyRewardEditFieldLabel     matlab.ui.control.Label
        InterTrialDelayEditField        matlab.ui.control.NumericEditField
        InterTrialDelayEditFieldLabel   matlab.ui.control.Label
        TargetFixationOptionsPanel      matlab.ui.container.Panel
        TargetDiameterEditField         matlab.ui.control.NumericEditField
        TargetDiameterEditFieldLabel    matlab.ui.control.Label
        TargetMaintainEditField         matlab.ui.control.NumericEditField
        TargetMaintainEditFieldLabel    matlab.ui.control.Label
        TargetIntiationEditField        matlab.ui.control.NumericEditField
        TargetIntiationEditFieldLabel   matlab.ui.control.Label
        EyelinkPanel                    matlab.ui.container.Panel
        CalibPointsKnob                 matlab.ui.control.DiscreteKnob
        CalibPointsKnobLabel            matlab.ui.control.Label
        SampleRateKnob                  matlab.ui.control.DiscreteKnob
        SampleRateKnobLabel             matlab.ui.control.Label
    end

 
 properties (Access = public)
  ana % parsed settings
 end
 
 methods (Access = private)
  
  function parseSettings(app)
       app.ana = [];
       app.ana.uuid = num2str(dec2hex(floor((now - floor(now))*1e10)));
       
       app.ana.subject = app.SubjectEditField.Value;
       app.ana.comments = app.InfoEditField.Value;
       app.ana.ResultDir = app.SavePathEditField.Value;
       app.ana.gammaTable = app.CalibrationEditField.Value;
       app.ana.pixelsPerCm = app.PixelsPerCmEditField.Value;
       app.ana.distance = app.DistanceEditField.Value;
       app.ana.fixX = app.FixationXEditField.Value;
       app.ana.fixY = app.FixationYEditField.Value;
       app.ana.firstFixInit = app.FixInitiationEditField.Value;
       app.ana.firstFixDiameter = app.FixationDiameterEditField.Value;
       app.ana.firstFixTime = app.Fix1stDurationEditField.Value;
       app.ana.Rewardms = app.RewardmsEditField.Value;
       app.ana.backgroundColor = str2num(app.BackgroundColorEditField.Value);
       app.ana.DKL = app.DKLCheckBox.Value;
       app.ana.colour1 = str2num(app.Color1EditField.Value);
       app.ana.colour2 = str2num(app.Color2EditField.Value);
       app.ana.contrast1 = app.Contrast1EditField.Value;
       app.ana.contrast2 = app.Contrast2EditField.Value;
       app.ana.contrast3 = app.Contrast3EditField.Value;
       app.ana.radiusDivisor = app.RadiusDivisor.Value;
       app.ana.positionXY = str2num(app.PositionXYEditField.Value);
       app.ana.circle1Diameter = app.Circle1DiameterEditField.Value;
       app.ana.circle2Diameter = app.Circle2DiameterEditField.Value;
       app.ana.sigma1 = app.sigma1.Value;
       app.ana.sigma2 = app.sigma2.Value;
       app.ana.trialDelay = app.InterTrialDelayEditField.Value;
       app.ana.punishDelay = app.PunishTimeEditField.Value;
       app.ana.delayToChoice = str2num(app.DelayToChoiceEditField.Value);
       app.ana.trialNumber = app.TrialBlocksEditField.Value;
       app.ana.frequency = app.Contrast1EditField.Value;
       app.ana.sampleRate = str2num(app.SampleRateKnob.Value);
       app.ana.calibrationStyle = app.CalibPointsKnob.Value;
       app.ana.targetInitiation = app.TargetIntiationEditField.Value;
       app.ana.targetMaintain = app.TargetMaintainEditField.Value;
       app.ana.targetDiameter = app.TargetDiameterEditField.Value;
       app.ana.exclusionZone = str2num(app.ExclusionZoneEditField.Value);
       app.ana.arduinoPort = app.SerialPortNameEditField.Value;
       app.ana.fixOnlyReward = app.FixOnlyRewardEditField.Value;
       
       if strcmpi(app.DebugModeSwitch.Value,'On')
        app.ana.debug = true;
        app.ana.windowed = [0 0 1000 1000];
       else
        app.ana.debug = false;
        app.ana.windowed = [];
       end
       if strcmpi(app.ELDummySwitch.Value,'On')
        app.ana.isDummy = true;
       else
        app.ana.isDummy = false;
       end
       if strcmpi(app.SendTriggersSwitch.Value,'On')
           app.ana.sendTrigger = true;
       else
           app.ana.sendTrigger = false;
       end
       app.ana.plotAxis1 = app.TrainAxes;
       app.ana.plotAxis2 = app.TrainAxesPerf;
       app.ana.plotAxis3 = app.TrainAxesPerf2;
  end
  
        
 end
 

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CButton
        function CButtonPushed(app, event)
            [f, p] = uigetfile('*.mat','Select a Calibration File');
            app.CalibrationEditField.Value = [p f];
        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
           delete(app);
        end

        % Button pushed function: GOButton
        function GOButtonPushed(app, event)
           event.Source.BusyAction = 'cancel';
           event.Source.Interruptible = 'off';
           app.parseSettings();
           fprintf('\n=========GO BUTTON PUSHED============\n')
           trainColourCore(app.ana)
        end

        % Button pushed function: SPButton
        function SPButtonPushed(app, event)
           app.SavePathEditField.Value = uigetdir;
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
           app.parseSettings();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.TrainColourTestUIFigure = uifigure('Visible', 'off');
            app.TrainColourTestUIFigure.Name = 'Train Colour Test';
            app.TabGroup = uitabgroup(app.TrainColourTestUIFigure);
            app.SettingsTab = uitab(app.TabGroup);
            app.SettingsTab.Title = 'Settings';
            app.GeneralPanel = uipanel(app.SettingsTab);
            app.GeneralPanel.Title = 'General';
            app.DistanceEditFieldLabel = uilabel(app.GeneralPanel);
            app.DistanceEditFieldLabel.Text = 'Distance';
            app.DistanceEditField = uieditfield(app.GeneralPanel, 'numeric');
            app.DistanceEditField.Limits = [20 200];
            app.DistanceEditField.Value = 120;
            app.PixelsPerCmEditFieldLabel = uilabel(app.GeneralPanel);
            app.PixelsPerCmEditFieldLabel.Text = 'PixelsPerCm';
            app.PixelsPerCmEditField = uieditfield(app.GeneralPanel, 'numeric');
            app.PixelsPerCmEditField.Limits = [5 300];
            app.PixelsPerCmEditField.Value = 27;
            app.FixationXEditFieldLabel = uilabel(app.GeneralPanel);
            app.FixationXEditFieldLabel.Text = 'Fixation X';
            app.FixationXEditField = uieditfield(app.GeneralPanel, 'numeric');
            app.ELDummySwitchLabel = uilabel(app.GeneralPanel);
            app.ELDummySwitchLabel.Text = 'EL Dummy';
            app.ELDummySwitch = uiswitch(app.GeneralPanel, 'rocker');
            app.DebugModeSwitchLabel = uilabel(app.GeneralPanel);
            app.DebugModeSwitchLabel.Text = 'Debug Mode';
            app.DebugModeSwitch = uiswitch(app.GeneralPanel, 'rocker');
            app.FixationYEditFieldLabel = uilabel(app.GeneralPanel);
            app.FixationYEditFieldLabel.Text = 'Fixation Y';
            app.FixationYEditField = uieditfield(app.GeneralPanel, 'numeric');
            app.FixInitiationEditFieldLabel = uilabel(app.GeneralPanel);
            app.FixInitiationEditFieldLabel.Text = 'Fix Initiation';
            app.FixInitiationEditField = uieditfield(app.GeneralPanel, 'numeric');
            app.FixInitiationEditField.Value = 1;
            app.Fix1stDurationEditFieldLabel = uilabel(app.GeneralPanel);
            app.Fix1stDurationEditFieldLabel.Text = 'Fix 1st Duration';
            app.Fix1stDurationEditField = uieditfield(app.GeneralPanel, 'numeric');
            app.Fix1stDurationEditField.Limits = [0.1 20];
            app.Fix1stDurationEditField.Value = 0.5;
            app.FixationDiameterEditFieldLabel = uilabel(app.GeneralPanel);
            app.FixationDiameterEditFieldLabel.Text = 'Fixation Diameter';
            app.FixationDiameterEditField = uieditfield(app.GeneralPanel, 'numeric');
            app.FixationDiameterEditField.Limits = [0.1 20];
            app.FixationDiameterEditField.Value = 2;
            app.InfoEditFieldLabel = uilabel(app.GeneralPanel);
            app.InfoEditFieldLabel.Text = 'Info';
            app.InfoEditField = uieditfield(app.GeneralPanel, 'text');
            app.InfoEditField.Value = 'Colour task training';
            app.SubjectEditFieldLabel = uilabel(app.GeneralPanel);
            app.SubjectEditFieldLabel.Text = 'Subject';
            app.SubjectEditField = uieditfield(app.GeneralPanel, 'text');
            app.SubjectEditField.Value = 'DEBUG';
            app.SavePathEditFieldLabel = uilabel(app.GeneralPanel);
            app.SavePathEditFieldLabel.Text = 'Save Path';
            app.SavePathEditField = uieditfield(app.GeneralPanel, 'text');
            app.SavePathEditField.Value = '/home/aristotle/Desktop/Train/';
            app.CalibrationEditFieldLabel = uilabel(app.GeneralPanel);
            app.CalibrationEditFieldLabel.Text = 'Calibration';
            app.CalibrationEditField = uieditfield(app.GeneralPanel, 'text');
            app.CButton = uibutton(app.GeneralPanel, 'push');
            app.CButton.ButtonPushedFcn = createCallbackFcn(app, @CButtonPushed, true);
            app.CButton.Text = '...';
            app.SPButton = uibutton(app.GeneralPanel, 'push');
            app.SPButton.ButtonPushedFcn = createCallbackFcn(app, @SPButtonPushed, true);
            app.SPButton.Text = '...';
            app.RewardmsEditFieldLabel = uilabel(app.GeneralPanel);
            app.RewardmsEditFieldLabel.Text = 'Reward (ms)';
            app.RewardmsEditField = uieditfield(app.GeneralPanel, 'numeric');
            app.RewardmsEditField.Limits = [1 2000];
            app.RewardmsEditField.Value = 150;
            app.SendTriggersSwitchLabel = uilabel(app.GeneralPanel);
            app.SendTriggersSwitchLabel.Text = 'Send Triggers?';
            app.SendTriggersSwitch = uiswitch(app.GeneralPanel, 'rocker');
            app.StimulusOptionsPanel = uipanel(app.SettingsTab);
            app.StimulusOptionsPanel.Title = 'Stimulus Options';
            app.Color1EditFieldLabel = uilabel(app.StimulusOptionsPanel);
            app.Color1EditFieldLabel.Text = 'Color 1';
            app.Color1EditField = uieditfield(app.StimulusOptionsPanel, 'text');
            app.Color1EditField.Value = '[ 0.05 0 0 ]';
            app.Color2EditFieldLabel = uilabel(app.StimulusOptionsPanel);
            app.Color2EditFieldLabel.Text = 'Color 2';
            app.Color2EditField = uieditfield(app.StimulusOptionsPanel, 'text');
            app.Color2EditField.Value = '[ 0.05 180 0 ]';
            app.PositionXYEditFieldLabel = uilabel(app.StimulusOptionsPanel);
            app.PositionXYEditFieldLabel.Text = 'Position X & Y';
            app.PositionXYEditField = uieditfield(app.StimulusOptionsPanel, 'text');
            app.PositionXYEditField.Value = '5 5';
            app.Circle1DiameterEditFieldLabel = uilabel(app.StimulusOptionsPanel);
            app.Circle1DiameterEditFieldLabel.Text = 'Circle 1 Diameter';
            app.Circle1DiameterEditField = uieditfield(app.StimulusOptionsPanel, 'numeric');
            app.Circle1DiameterEditField.Limits = [1 40];
            app.Circle1DiameterEditField.Value = 6;
            app.BackgroundColorEditFieldLabel = uilabel(app.StimulusOptionsPanel);
            app.BackgroundColorEditFieldLabel.Text = 'Background Color';
            app.BackgroundColorEditField = uieditfield(app.StimulusOptionsPanel, 'text');
            app.BackgroundColorEditField.Value = '[ 0.5 0.5 0.5 ]';
            app.Contrast1EditFieldLabel = uilabel(app.StimulusOptionsPanel);
            app.Contrast1EditFieldLabel.Text = 'Contrast 1';
            app.Contrast1EditField = uieditfield(app.StimulusOptionsPanel, 'numeric');
            app.Contrast1EditField.Limits = [-90 90];
            app.Contrast1EditField.ValueDisplayFormat = '%11.5g';
            app.Contrast1EditField.Value = 70;
            app.TrialBlocksEditFieldLabel = uilabel(app.StimulusOptionsPanel);
            app.TrialBlocksEditFieldLabel.Text = 'Trial Blocks';
            app.TrialBlocksEditField = uieditfield(app.StimulusOptionsPanel, 'numeric');
            app.TrialBlocksEditField.Limits = [1 600];
            app.TrialBlocksEditField.Value = 5;
            app.Circle2DiameterEditFieldLabel = uilabel(app.StimulusOptionsPanel);
            app.Circle2DiameterEditFieldLabel.Text = 'Circle 2 Diameter';
            app.Circle2DiameterEditField = uieditfield(app.StimulusOptionsPanel, 'numeric');
            app.Circle2DiameterEditField.Limits = [1 40];
            app.Circle2DiameterEditField.Value = 6;
            app.sigma1 = uieditfield(app.StimulusOptionsPanel, 'numeric');
            app.sigma1.Limits = [0 501];
            app.sigma1.Tooltip = {'Smoothing sigma'};
            app.sigma1.Value = 11;
            app.sigma2 = uieditfield(app.StimulusOptionsPanel, 'numeric');
            app.sigma2.Limits = [0 501];
            app.sigma2.Tooltip = {'Smoothing sigma'};
            app.sigma2.Value = 11;
            app.DelayToChoiceEditFieldLabel = uilabel(app.StimulusOptionsPanel);
            app.DelayToChoiceEditFieldLabel.Text = 'Delay To Choice';
            app.DelayToChoiceEditField = uieditfield(app.StimulusOptionsPanel, 'text');
            app.DelayToChoiceEditField.Value = '[ 0.6 0.8 ]';
            app.Contrast2EditFieldLabel = uilabel(app.StimulusOptionsPanel);
            app.Contrast2EditFieldLabel.Text = 'Contrast 2';
            app.Contrast2EditField = uieditfield(app.StimulusOptionsPanel, 'numeric');
            app.Contrast2EditField.Limits = [-90 90];
            app.Contrast2EditField.ValueDisplayFormat = '%11.5g';
            app.Contrast2EditField.Value = -50;
            app.PunishTimeEditFieldLabel = uilabel(app.StimulusOptionsPanel);
            app.PunishTimeEditFieldLabel.Text = 'Punish Time';
            app.PunishTimeEditField = uieditfield(app.StimulusOptionsPanel, 'numeric');
            app.PunishTimeEditField.Limits = [0 40];
            app.PunishTimeEditField.ValueDisplayFormat = '%11.5g';
            app.PunishTimeEditField.Value = 3;
            app.DKLCheckBox = uicheckbox(app.StimulusOptionsPanel);
            app.DKLCheckBox.Tooltip = {'Use DKL colour space'};
            app.DKLCheckBox.Text = 'DKL';
            app.DKLCheckBox.Value = true;
            app.RadiusDivisor = uieditfield(app.StimulusOptionsPanel, 'numeric');
            app.RadiusDivisor.Limits = [0 100];
            app.RadiusDivisor.Tooltip = {'Radius divisor'};
            app.RadiusDivisor.Value = 4;
            app.Contrast3EditFieldLabel = uilabel(app.StimulusOptionsPanel);
            app.Contrast3EditFieldLabel.Text = 'Contrast 3';
            app.Contrast3EditField = uieditfield(app.StimulusOptionsPanel, 'numeric');
            app.Contrast3EditField.Limits = [-90 90];
            app.Contrast3EditField.ValueDisplayFormat = '%11.5g';
            app.Contrast3EditField.Value = -70;
            app.ControlPanel = uipanel(app.SettingsTab);
            app.ControlPanel.Title = 'Control';
            app.GOButton = uibutton(app.ControlPanel, 'push');
            app.GOButton.ButtonPushedFcn = createCallbackFcn(app, @GOButtonPushed, true);
            app.GOButton.Text = 'GO!';
            app.SaveButton = uibutton(app.ControlPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Enable = 'off';
            app.SaveButton.Text = 'Save!';
            app.ExitButton = uibutton(app.ControlPanel, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit!';
            app.ExperimentalRunTab = uitab(app.TabGroup);
            app.ExperimentalRunTab.Title = 'Experimental Run';
            app.TrainAxes = uiaxes(app.ExperimentalRunTab);
            title(app.TrainAxes, 'Trial Results')
            xlabel(app.TrainAxes, 'Type')
            ylabel(app.TrainAxes, '# of trials')
            app.TrainAxesPerf = uiaxes(app.ExperimentalRunTab);
            title(app.TrainAxesPerf, 'Overall Performance')
            xlabel(app.TrainAxesPerf, 'Trial')
            ylabel(app.TrainAxesPerf, '% success')
            app.TrainAxesPerf2 = uiaxes(app.ExperimentalRunTab);
            title(app.TrainAxesPerf2, 'Recent Performance')
            xlabel(app.TrainAxesPerf2, 'Trial Average (last 10)')
            ylabel(app.TrainAxesPerf2, '% success')
            app.MoreSettingsTab = uitab(app.TabGroup);
            app.MoreSettingsTab.Title = 'More Settings...';
            app.EyelinkPanel = uipanel(app.MoreSettingsTab);
            app.EyelinkPanel.Title = 'Eyelink';
            app.SampleRateKnobLabel = uilabel(app.EyelinkPanel);
            app.SampleRateKnobLabel.Text = 'Sample Rate';
            app.SampleRateKnob = uiknob(app.EyelinkPanel, 'discrete');
            app.SampleRateKnob.Items = {'250', '500', '1000', '2000'};
            app.SampleRateKnob.Value = '250';
            app.CalibPointsKnobLabel = uilabel(app.EyelinkPanel);
            app.CalibPointsKnobLabel.Text = 'Calib Points';
            app.CalibPointsKnob = uiknob(app.EyelinkPanel, 'discrete');
            app.CalibPointsKnob.Items = {'HV3', 'HV5', 'HV9', 'HV13'};
            app.CalibPointsKnob.Value = 'HV5';
            app.TargetFixationOptionsPanel = uipanel(app.MoreSettingsTab);
            app.TargetFixationOptionsPanel.Title = 'Target Fixation Options';
            app.TargetIntiationEditFieldLabel = uilabel(app.TargetFixationOptionsPanel);
            app.TargetIntiationEditFieldLabel.Text = 'Target Intiation';
            app.TargetIntiationEditField = uieditfield(app.TargetFixationOptionsPanel, 'numeric');
            app.TargetIntiationEditField.Limits = [0.01 20];
            app.TargetIntiationEditField.Value = 0.5;
            app.TargetMaintainEditFieldLabel = uilabel(app.TargetFixationOptionsPanel);
            app.TargetMaintainEditFieldLabel.Text = 'Target Maintain';
            app.TargetMaintainEditField = uieditfield(app.TargetFixationOptionsPanel, 'numeric');
            app.TargetMaintainEditField.Limits = [0.01 20];
            app.TargetMaintainEditField.Value = 0.5;
            app.TargetDiameterEditFieldLabel = uilabel(app.TargetFixationOptionsPanel);
            app.TargetDiameterEditFieldLabel.Text = 'Target Diameter';
            app.TargetDiameterEditField = uieditfield(app.TargetFixationOptionsPanel, 'numeric');
            app.TargetDiameterEditField.Limits = [0.1 20];
            app.TargetDiameterEditField.Value = 6;
            app.OthertrainingparametersPanel = uipanel(app.MoreSettingsTab);
            app.OthertrainingparametersPanel.Title = 'Other training parameters';
            app.InterTrialDelayEditFieldLabel = uilabel(app.OthertrainingparametersPanel);
            app.InterTrialDelayEditFieldLabel.Text = 'InterTrial Delay';
            app.InterTrialDelayEditField = uieditfield(app.OthertrainingparametersPanel, 'numeric');
            app.InterTrialDelayEditField.Limits = [0.01 20];
            app.InterTrialDelayEditField.Value = 0.75;
            app.FixOnlyRewardEditFieldLabel = uilabel(app.OthertrainingparametersPanel);
            app.FixOnlyRewardEditFieldLabel.Text = '% Fix Only Reward';
            app.FixOnlyRewardEditField = uieditfield(app.OthertrainingparametersPanel, 'numeric');
            app.FixOnlyRewardEditField.Limits = [0 100];
            app.FixOnlyRewardEditField.RoundFractionalValues = 'on';
            app.FixOnlyRewardEditField.ValueDisplayFormat = '%.0f';
            app.FixOnlyRewardEditField.Value = 10;
            app.ExclusionZoneEditFieldLabel = uilabel(app.OthertrainingparametersPanel);
            app.ExclusionZoneEditFieldLabel.Text = 'Exclusion Zone';
            app.ExclusionZoneEditField = uieditfield(app.OthertrainingparametersPanel, 'text');
            app.ExclusionZoneEditField.Value = '[ ]';
            app.ArduinoPanel = uipanel(app.MoreSettingsTab);
            app.ArduinoPanel.Title = 'Arduino';
            app.SerialPortNameEditFieldLabel = uilabel(app.ArduinoPanel);
            app.SerialPortNameEditFieldLabel.Text = 'Serial Port Name';
            app.SerialPortNameEditField = uieditfield(app.ArduinoPanel, 'text');
            app.SerialPortNameEditField.Value = '/dev/ttyACM1';
            % Show the figure after all components are created
            app.TrainColourTestUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = trainColour

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.TrainColourTestUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TrainColourTestUIFigure)
        end
    end
end