classdef trainColourCued < matlab.apps.AppBase

	% Properties that correspond to app components
	properties (Access = public)
		TrainColourCuedUIFigure        matlab.ui.Figure
		TabGroup                       matlab.ui.container.TabGroup
		SettingsTab                    matlab.ui.container.Tab
		TaskParametersPanel            matlab.ui.container.Panel
		FixInitTime                    matlab.ui.control.NumericEditField
		InitiateSaccadeDelaySwitch     matlab.ui.control.RockerSwitch
		InitiateSaccadeDelaySwitchLabel  matlab.ui.control.Label
		UseExclusionZonesSwitch        matlab.ui.control.RockerSwitch
		UseExclusionZonesSwitchLabel   matlab.ui.control.Label
		ITIEditField                   matlab.ui.control.NumericEditField
		ITIEditFieldLabel              matlab.ui.control.Label
		MaintainChoiceEditField        matlab.ui.control.NumericEditField
		MaintainChoiceEditFieldLabel   matlab.ui.control.Label
		InitiateChoiceEditField        matlab.ui.control.NumericEditField
		InitiateChoiceEditFieldLabel   matlab.ui.control.Label
		RewardmsEditField              matlab.ui.control.NumericEditField
		RewardmsEditFieldLabel         matlab.ui.control.Label
		TrialBlocksEditField           matlab.ui.control.NumericEditField
		TrialBlocksEditFieldLabel      matlab.ui.control.Label
		PunishTimeEditField            matlab.ui.control.NumericEditField
		PunishTimeEditFieldLabel       matlab.ui.control.Label
		DelayTimeEditField             matlab.ui.control.EditField
		DelayTimeEditField_2Label      matlab.ui.control.Label
		CueSizeEditField               matlab.ui.control.NumericEditField
		CueSizeEditFieldLabel          matlab.ui.control.Label
		CueTimeEditField               matlab.ui.control.NumericEditField
		CueTimeEditFieldLabel          matlab.ui.control.Label
		EyelinkPanel                   matlab.ui.container.Panel
		CalibPropEditField             matlab.ui.control.EditField
		CalibPropEditFieldLabel        matlab.ui.control.Label
		ManualSwitch                   matlab.ui.control.RockerSwitch
		ManualSwitchLabel              matlab.ui.control.Label
		StrictEditField                matlab.ui.control.NumericEditField
		StrictEditFieldLabel           matlab.ui.control.Label
		RadiusEditField                matlab.ui.control.NumericEditField
		RadiusEditFieldLabel           matlab.ui.control.Label
		DurationEditField              matlab.ui.control.NumericEditField
		DurationEditFieldLabel         matlab.ui.control.Label
		InitEditField                  matlab.ui.control.NumericEditField
		InitEditFieldLabel             matlab.ui.control.Label
		YEditField                     matlab.ui.control.NumericEditField
		YEditFieldLabel                matlab.ui.control.Label
		XEditField                     matlab.ui.control.NumericEditField
		XEditFieldLabel                matlab.ui.control.Label
		DummySwitch                    matlab.ui.control.RockerSwitch
		DummySwitchLabel               matlab.ui.control.Label
		CalibPointsKnob                matlab.ui.control.DiscreteKnob
		CalibPointsKnobLabel           matlab.ui.control.Label
		SampleRateKnob                 matlab.ui.control.DiscreteKnob
		SampleRateKnobLabel            matlab.ui.control.Label
		ControlPanel                   matlab.ui.container.Panel
		FixOnlySwitch                  matlab.ui.control.RockerSwitch
		FixOnlySwitchLabel             matlab.ui.control.Label
		DebugModeSwitch                matlab.ui.control.RockerSwitch
		DebugModeLabel                 matlab.ui.control.Label
		SendTriggersSwitch             matlab.ui.control.RockerSwitch
		SendTriggersSwitchLabel        matlab.ui.control.Label
		ExitButton                     matlab.ui.control.Button
		SaveButton                     matlab.ui.control.Button
		GOButton                       matlab.ui.control.Button
		StimulusOptionsPanel           matlab.ui.container.Panel
		Position4EditField             matlab.ui.control.EditField
		Position4EditFieldLabel        matlab.ui.control.Label
		Position3EditField             matlab.ui.control.EditField
		Position3EditFieldLabel        matlab.ui.control.Label
		Position2EditField             matlab.ui.control.EditField
		Position2EditFieldLabel        matlab.ui.control.Label
		Position1EditField             matlab.ui.control.EditField
		Position1EditFieldLabel        matlab.ui.control.Label
		ShowCheckBox                   matlab.ui.control.CheckBox
		circle4alpha                   matlab.ui.control.NumericEditField
		circle3alpha                   matlab.ui.control.NumericEditField
		ColorsEditField                matlab.ui.control.EditField
		ColorsEditFieldLabel           matlab.ui.control.Label
		circle2alpha                   matlab.ui.control.NumericEditField
		sigma4                         matlab.ui.control.NumericEditField
		Circle4DiameterEditField       matlab.ui.control.NumericEditField
		Circle4DiameterEditFieldLabel  matlab.ui.control.Label
		sigma3                         matlab.ui.control.NumericEditField
		Circle3DiameterEditField       matlab.ui.control.NumericEditField
		Circle3DiameterEditFieldLabel  matlab.ui.control.Label
		DKLCheckBox                    matlab.ui.control.CheckBox
		sigma2                         matlab.ui.control.NumericEditField
		sigma1                         matlab.ui.control.NumericEditField
		Circle2DiameterEditField       matlab.ui.control.NumericEditField
		Circle2DiameterEditFieldLabel  matlab.ui.control.Label
		BackgroundColorEditField       matlab.ui.control.EditField
		BackgroundColorEditFieldLabel  matlab.ui.control.Label
		Circle1DiameterEditField       matlab.ui.control.NumericEditField
		Circle1DiameterEditFieldLabel  matlab.ui.control.Label
		Color2EditField                matlab.ui.control.EditField
		Color2EditFieldLabel           matlab.ui.control.Label
		Color1EditField                matlab.ui.control.EditField
		Color1EditFieldLabel           matlab.ui.control.Label
		GeneralPanel                   matlab.ui.container.Panel
		UseArduinoSwitch               matlab.ui.control.RockerSwitch
		UseArduinoSwitchLabel          matlab.ui.control.Label
		ArduinoPortEditField           matlab.ui.control.EditField
		ArduinoPortEditFieldLabel      matlab.ui.control.Label
		bitDepthDropDown               matlab.ui.control.DropDown
		bitDepthDropDownLabel          matlab.ui.control.Label
		SPButton                       matlab.ui.control.Button
		CButton                        matlab.ui.control.Button
		CalibrationEditField           matlab.ui.control.EditField
		CalibrationEditFieldLabel      matlab.ui.control.Label
		SavePathEditField              matlab.ui.control.EditField
		SavePathEditFieldLabel         matlab.ui.control.Label
		SubjectEditField               matlab.ui.control.EditField
		SubjectEditFieldLabel          matlab.ui.control.Label
		InfoEditField                  matlab.ui.control.EditField
		InfoEditFieldLabel             matlab.ui.control.Label
		PixelsPerCmEditField           matlab.ui.control.NumericEditField
		PixelsPerCmEditFieldLabel      matlab.ui.control.Label
		DistanceEditField              matlab.ui.control.NumericEditField
		DistanceEditFieldLabel         matlab.ui.control.Label
		OnlinePlotsTab                 matlab.ui.container.Tab
		TrainAxes4                     matlab.ui.control.UIAxes
		TrainAxes3                     matlab.ui.control.UIAxes
		TrainAxes2                     matlab.ui.control.UIAxes
		TrainAxes1                     matlab.ui.control.UIAxes
		MoreSettingsTab                matlab.ui.container.Tab
		ArduinoPanel                   matlab.ui.container.Panel
		OthertrainingparametersPanel   matlab.ui.container.Panel
		ExclusionZoneEditField         matlab.ui.control.EditField
		ExclusionZoneEditFieldLabel    matlab.ui.control.Label
		FixOnlyRewardEditField         matlab.ui.control.NumericEditField
		FixOnlyRewardEditFieldLabel    matlab.ui.control.Label
		InterTrialDelayEditField       matlab.ui.control.NumericEditField
		InterTrialDelayEditFieldLabel  matlab.ui.control.Label
	end


	properties (Access = public)
		ana % parsed settings
	end

	methods (Access = private)

		function parseSettings(app)
			app.ana = [];
			app.ana.uuid = num2str(dec2hex(floor((now - floor(now))*1e10)));

			% General
			app.ana.subject			= app.SubjectEditField.Value;
			app.ana.comments		= app.InfoEditField.Value;
			app.ana.ResultDir		= app.SavePathEditField.Value;
			app.ana.gammaTable		= app.CalibrationEditField.Value;
			app.ana.pixelsPerCm		= app.PixelsPerCmEditField.Value;
			app.ana.distance		= app.DistanceEditField.Value;
			app.ana.arduinoPort		= app.ArduinoPortEditField.Value;
			app.ana.bitDepth		= app.bitDepthDropDown.Value;

			% Eyelink
			app.ana.fixX			= app.XEditField.Value;
			app.ana.fixY			= app.YEditField.Value;
			app.ana.firstFixInit	= app.InitEditField.Value;
			app.ana.firstFixDiameter = app.RadiusEditField.Value;
			app.ana.firstFixTime	= app.DurationEditField.Value;
			app.ana.strictFixation	= logical(app.StrictEditField.Value);
			app.ana.calibprop		= str2num(app.CalibPropEditField.Value);

			% Stimulus
			app.ana.backgroundColour = str2num(app.BackgroundColorEditField.Value);
			app.ana.DKL				= logical(app.DKLCheckBox.Value);
			app.ana.colour1			= str2num(app.Color1EditField.Value);
			app.ana.colour2			= str2num(app.Color2EditField.Value);
			app.ana.colours			= app.ColorsEditField.Value;
			app.ana.circle1Diameter = app.Circle1DiameterEditField.Value;
			app.ana.circle2Diameter = app.Circle2DiameterEditField.Value;
			app.ana.circle3Diameter = app.Circle3DiameterEditField.Value;
			app.ana.circle4Diameter = app.Circle4DiameterEditField.Value;
			app.ana.sigma1			= app.sigma1.Value;
			app.ana.sigma2			= app.sigma2.Value;
			app.ana.sigma3			= app.sigma3.Value;
			app.ana.sigma4			= app.sigma4.Value;
			app.ana.alpha2			= app.circle2alpha.Value;
			app.ana.alpha3			= app.circle3alpha.Value;
			app.ana.alpha4			= app.circle4alpha.Value;
			app.ana.show34			= logical(app.ShowCheckBox.Value);
			app.ana.pos1			= str2num(app.Position1EditField.Value);
			app.ana.pos2			= str2num(app.Position2EditField.Value);
			app.ana.pos3			= str2num(app.Position3EditField.Value);
			app.ana.pos4			= str2num(app.Position4EditField.Value);

			% Task
			app.ana.cueTime			= app.CueTimeEditField.Value;
			app.ana.cueSize			= app.CueSizeEditField.Value;
			app.ana.trialDelay		= app.DelayTimeEditField.Value;
			app.ana.punishDelay		= app.PunishTimeEditField.Value;
			app.ana.delayToChoice	= str2num(app.DelayTimeEditField.Value);
			app.ana.trialNumber		= app.TrialBlocksEditField.Value;
			app.ana.Rewardms		= app.RewardmsEditField.Value;
			app.ana.initiateChoice	= app.InitiateChoiceEditField.Value;
			app.ana.maintainChoice	= app.MaintainChoiceEditField.Value;
			app.ana.fixInitTime		= app.FixInitTime.Value;

			% Others
			app.ana.sampleRate = str2num(app.SampleRateKnob.Value);
			app.ana.calibrationStyle = app.CalibPointsKnob.Value;

			if strcmpi(app.UseArduinoSwitch.Value,'On')
				app.ana.useArduino = true;
			else
				app.ana.useArduino = false;
			end
			if strcmpi(app.UseExclusionZonesSwitch.Value,'On')
				app.ana.exclusion = true;
			else
				app.ana.exclusion = false;
			end
			if strcmpi(app.InitiateSaccadeDelaySwitch.Value,'On')
				app.ana.fixinit = true;
			else
				app.ana.fixinit = false;
			end
			if strcmpi(app.DebugModeSwitch.Value,'On')
				app.ana.debug = true;
				app.ana.windowed = [0 0 1000 1000];
			else
				app.ana.debug = false;
				app.ana.windowed = [];
			end
			if strcmpi(app.DummySwitch.Value,'On')
				app.ana.isDummy = true;
			else
				app.ana.isDummy = false;
			end
			if strcmpi(app.ManualSwitch.Value,'On')
				app.ana.manualCalibration = true;
			else
				app.ana.manualCalibration = false;
			end
			if strcmpi(app.SendTriggersSwitch.Value,'On')
				app.ana.sendTrigger = true;
			else
				app.ana.sendTrigger = false;
			end
			if strcmpi(app.FixOnlySwitch.Value,'On')
				app.ana.fixOnly = true;
			else
				app.ana.fixOnly = false;
			end
			app.ana.plotAxis1 = app.TrainAxes1;
			app.ana.plotAxis2 = app.TrainAxes2;
			app.ana.plotAxis3 = app.TrainAxes3;
			app.ana.plotAxis4 = app.TrainAxes4;
		end

		function p = setPath(app)
			s=metaStimulus();
			app.SavePathEditField.Value=s.paths.savedData;
			p = app.SavePathEditField.Value;
		end
	end


	% Callbacks that handle component events
	methods (Access = private)

		% Code that executes after component creation
		function startupFcn(app)
			r = groot;
			app.TrainColourCuedUIFigure.Position(1) = (r.ScreenSize(3)/2) - app.TrainColourCuedUIFigure.Position(3);
			app.TrainColourCuedUIFigure.Position(2) = r.ScreenSize(4) - app.TrainColourCuedUIFigure.Position(4);
			p=app.setPath();
			cd(p);
		end

		% Button pushed function: CButton
		function CButtonPushed(app, event)
			[f, p] = uigetfile('*.mat','Select a Calibration File');
			app.CalibrationEditField.Value = [p f];
		end

		% Button pushed function: ExitButton
		function ExitButtonPushed(app, event)
			delete(app);
		end

		% Button pushed function: GOButton
		function GOButtonPushed(app, event)
			app.parseSettings();
			fprintf('\n=========GO BUTTON PUSHED============\n')
			commandwindow;
			ana = app.ana;
			ana.plotAxis1 = [];
			ana.plotAxis2 = [];
			ana.plotAxis3 = [];
			ana.plotAxis4 = [];
			%writestruct(ana,'~/Desktop/ana.xml');
			trainColourCuedCore(app.ana);
		end

		% Button pushed function: SPButton
		function SPButtonPushed(app, event)
			app.SavePathEditField.Value = uigetdir;
		end

		% Button pushed function: SaveButton
		function SaveButtonPushed(app, event)
			app.parseSettings();
		end

		% Value changed function: ShowCheckBox
		function ShowCheckBoxValueChanged(app, event)
			value = app.ShowCheckBox.Value;
			app.circle4alpha.Enable = logical(value);
			app.circle3alpha.Enable = logical(value);
			app.sigma4.Enable = logical(value);
			app.sigma3.Enable = logical(value);
			app.Circle4DiameterEditField.Enable = logical(value);
			app.Circle3DiameterEditField.Enable = logical(value);
			app.Circle4DiameterEditFieldLabel.Enable = logical(value);
			app.Circle3DiameterEditFieldLabel.Enable = logical(value);
		end
	end

	% Component initialization
	methods (Access = private)

		% Create UIFigure and components
		function createComponents(app)
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			app.TrainColourCuedUIFigure = uifigure('Visible', 'off');
			app.TrainColourCuedUIFigure.Name = 'Train Colour Cued';
			app.TabGroup = uitabgroup(app.TrainColourCuedUIFigure);
			app.SettingsTab = uitab(app.TabGroup);
			app.SettingsTab.Title = 'Settings';
			app.GeneralPanel = uipanel(app.SettingsTab);
			app.GeneralPanel.Title = 'General';
			app.DistanceEditFieldLabel = uilabel(app.GeneralPanel);
			app.DistanceEditFieldLabel.Text = 'Distance';
			app.DistanceEditField = uieditfield(app.GeneralPanel, 'numeric');
			app.DistanceEditField.Limits = [20 200];
			app.DistanceEditField.Value = 120;
			app.PixelsPerCmEditFieldLabel = uilabel(app.GeneralPanel);
			app.PixelsPerCmEditFieldLabel.Text = 'PixelsPerCm';
			app.PixelsPerCmEditField = uieditfield(app.GeneralPanel, 'numeric');
			app.PixelsPerCmEditField.Limits = [5 300];
			app.PixelsPerCmEditField.Value = 27;
			app.InfoEditFieldLabel = uilabel(app.GeneralPanel);
			app.InfoEditFieldLabel.Text = 'Info';
			app.InfoEditField = uieditfield(app.GeneralPanel, 'text');
			app.InfoEditField.Value = 'Colour Cue Task';
			app.SubjectEditFieldLabel = uilabel(app.GeneralPanel);
			app.SubjectEditFieldLabel.Text = 'Subject';
			app.SubjectEditField = uieditfield(app.GeneralPanel, 'text');
			app.SubjectEditField.Value = 'DEBUG';
			app.SavePathEditFieldLabel = uilabel(app.GeneralPanel);
			app.SavePathEditFieldLabel.Text = 'Save Path';
			app.SavePathEditField = uieditfield(app.GeneralPanel, 'text');
			app.SavePathEditField.Value = '/home/aristotle/Desktop/Train/';
			app.CalibrationEditFieldLabel = uilabel(app.GeneralPanel);
			app.CalibrationEditFieldLabel.Text = 'Calibration';
			app.CalibrationEditField = uieditfield(app.GeneralPanel, 'text');
			app.CButton = uibutton(app.GeneralPanel, 'push');
			app.CButton.ButtonPushedFcn = createCallbackFcn(app, @CButtonPushed, true);
			app.CButton.Icon = 'go-to-file.png';
			app.CButton.Text = '';
			app.SPButton = uibutton(app.GeneralPanel, 'push');
			app.SPButton.ButtonPushedFcn = createCallbackFcn(app, @SPButtonPushed, true);
			app.SPButton.Icon = 'go-to-file.png';
			app.SPButton.Text = '';
			app.bitDepthDropDownLabel = uilabel(app.GeneralPanel);
			app.bitDepthDropDownLabel.Text = 'bitDepth';
			app.bitDepthDropDown = uidropdown(app.GeneralPanel);
			app.bitDepthDropDown.Items = {'8bit', 'FloatingPoint32BitIfPossible', 'Native10bit', 'Bits++Color++Output', 'Bits++Bits++Output'};
			app.bitDepthDropDown.Value = 'Bits++Bits++Output';
			app.ArduinoPortEditFieldLabel = uilabel(app.GeneralPanel);
			app.ArduinoPortEditFieldLabel.Text = 'Arduino Port';
			app.ArduinoPortEditField = uieditfield(app.GeneralPanel, 'text');
			app.ArduinoPortEditField.Value = '/dev/ttyACM1';
			app.UseArduinoSwitchLabel = uilabel(app.GeneralPanel);
			app.UseArduinoSwitchLabel.Text = 'Use Arduino?';
			app.UseArduinoSwitch = uiswitch(app.GeneralPanel, 'rocker');
			app.UseArduinoSwitch.Value = 'On';
			app.StimulusOptionsPanel = uipanel(app.SettingsTab);
			app.StimulusOptionsPanel.Title = 'Stimulus Options';
			app.Color1EditFieldLabel = uilabel(app.StimulusOptionsPanel);
			app.Color1EditFieldLabel.Text = 'Color 1';
			app.Color1EditField = uieditfield(app.StimulusOptionsPanel, 'text');
			app.Color1EditField.Value = '0.7 0.2 0.2';
			app.Color2EditFieldLabel = uilabel(app.StimulusOptionsPanel);
			app.Color2EditFieldLabel.Text = 'Color 2';
			app.Color2EditField = uieditfield(app.StimulusOptionsPanel, 'text');
			app.Color2EditField.Value = '0.25 0.55 0.25';
			app.Circle1DiameterEditFieldLabel = uilabel(app.StimulusOptionsPanel);
			app.Circle1DiameterEditFieldLabel.Text = 'Circle 1 Diameter';
			app.Circle1DiameterEditField = uieditfield(app.StimulusOptionsPanel, 'numeric');
			app.Circle1DiameterEditField.Limits = [1 40];
			app.Circle1DiameterEditField.Tooltip = {'Disc diameter in degrees'};
			app.Circle1DiameterEditField.Value = 6;
			app.BackgroundColorEditFieldLabel = uilabel(app.StimulusOptionsPanel);
			app.BackgroundColorEditFieldLabel.Text = 'Background Color';
			app.BackgroundColorEditField = uieditfield(app.StimulusOptionsPanel, 'text');
			app.BackgroundColorEditField.Value = '0.5 0.5 0.5';
			app.Circle2DiameterEditFieldLabel = uilabel(app.StimulusOptionsPanel);
			app.Circle2DiameterEditFieldLabel.Text = 'Circle 2 Diameter';
			app.Circle2DiameterEditField = uieditfield(app.StimulusOptionsPanel, 'numeric');
			app.Circle2DiameterEditField.Limits = [1 40];
			app.Circle2DiameterEditField.Tooltip = {'Disc diameter in degrees'};
			app.Circle2DiameterEditField.Value = 6;
			app.sigma1 = uieditfield(app.StimulusOptionsPanel, 'numeric');
			app.sigma1.Limits = [0 501];
			app.sigma1.Tooltip = {'Smoothing sigma'};
			app.sigma1.Value = 21;
			app.sigma2 = uieditfield(app.StimulusOptionsPanel, 'numeric');
			app.sigma2.Limits = [0 501];
			app.sigma2.Tooltip = {'Smoothing sigma'};
			app.sigma2.Value = 21;
			app.DKLCheckBox = uicheckbox(app.StimulusOptionsPanel);
			app.DKLCheckBox.Enable = 'off';
			app.DKLCheckBox.Tooltip = {'Use DKL colour space'};
			app.DKLCheckBox.Text = 'DKL';
			app.Circle3DiameterEditFieldLabel = uilabel(app.StimulusOptionsPanel);
			app.Circle3DiameterEditFieldLabel.Text = 'Circle 3 Diameter';
			app.Circle3DiameterEditField = uieditfield(app.StimulusOptionsPanel, 'numeric');
			app.Circle3DiameterEditField.Limits = [1 40];
			app.Circle3DiameterEditField.Tooltip = {'Disc diameter in degrees'};
			app.Circle3DiameterEditField.Value = 6;
			app.sigma3 = uieditfield(app.StimulusOptionsPanel, 'numeric');
			app.sigma3.Limits = [0 501];
			app.sigma3.Tooltip = {'Smoothing sigma'};
			app.sigma3.Value = 21;
			app.Circle4DiameterEditFieldLabel = uilabel(app.StimulusOptionsPanel);
			app.Circle4DiameterEditFieldLabel.Text = 'Circle 4 Diameter';
			app.Circle4DiameterEditField = uieditfield(app.StimulusOptionsPanel, 'numeric');
			app.Circle4DiameterEditField.Limits = [1 40];
			app.Circle4DiameterEditField.Tooltip = {'Disc diameter in degrees'};
			app.Circle4DiameterEditField.Value = 6;
			app.sigma4 = uieditfield(app.StimulusOptionsPanel, 'numeric');
			app.sigma4.Limits = [0 501];
			app.sigma4.Tooltip = {'Smoothing sigma'};
			app.sigma4.Value = 21;
			app.circle2alpha = uieditfield(app.StimulusOptionsPanel, 'numeric');
			app.circle2alpha.Limits = [0 501];
			app.circle2alpha.Tooltip = {'Alpha Opacity'};
			app.circle2alpha.Value = 0.1;
			app.ColorsEditFieldLabel = uilabel(app.StimulusOptionsPanel);
			app.ColorsEditFieldLabel.Text = 'Colors';
			app.ColorsEditField = uieditfield(app.StimulusOptionsPanel, 'text');
			app.ColorsEditField.Value = '{[0.7 0.2 0.2],[0.25 0.55 0.25],[0.7 0.55 0],[0.2 0.2 0.8],[0.7 0.2 0.7]}';
			app.circle3alpha = uieditfield(app.StimulusOptionsPanel, 'numeric');
			app.circle3alpha.Limits = [0 501];
			app.circle3alpha.Tooltip = {'Alpha Opacity'};
			app.circle3alpha.Value = 0.1;
			app.circle4alpha = uieditfield(app.StimulusOptionsPanel, 'numeric');
			app.circle4alpha.Limits = [0 501];
			app.circle4alpha.Tooltip = {'Alpha Opacity'};
			app.circle4alpha.Value = 0.1;
			app.ShowCheckBox = uicheckbox(app.StimulusOptionsPanel);
			app.ShowCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowCheckBoxValueChanged, true);
			app.ShowCheckBox.Text = 'Show';
			app.ShowCheckBox.Value = true;
			app.Position1EditFieldLabel = uilabel(app.StimulusOptionsPanel);
			app.Position1EditFieldLabel.Text = 'Position 1';
			app.Position1EditField = uieditfield(app.StimulusOptionsPanel, 'text');
			app.Position1EditField.Value = '-6 -6';
			app.Position2EditFieldLabel = uilabel(app.StimulusOptionsPanel);
			app.Position2EditFieldLabel.Text = 'Position 2';
			app.Position2EditField = uieditfield(app.StimulusOptionsPanel, 'text');
			app.Position2EditField.Value = '6 -6';
			app.Position3EditFieldLabel = uilabel(app.StimulusOptionsPanel);
			app.Position3EditFieldLabel.Text = 'Position 3';
			app.Position3EditField = uieditfield(app.StimulusOptionsPanel, 'text');
			app.Position3EditField.Value = '-6 6';
			app.Position4EditFieldLabel = uilabel(app.StimulusOptionsPanel);
			app.Position4EditFieldLabel.Text = 'Position 4';
			app.Position4EditField = uieditfield(app.StimulusOptionsPanel, 'text');
			app.Position4EditField.Value = '6 6';
			app.ControlPanel = uipanel(app.SettingsTab);
			app.ControlPanel.Title = 'Control';
			app.GOButton = uibutton(app.ControlPanel, 'push');
			app.GOButton.ButtonPushedFcn = createCallbackFcn(app, @GOButtonPushed, true);
			app.GOButton.Icon = 'start.png';
			app.GOButton.Text = 'GO!';
			app.SaveButton = uibutton(app.ControlPanel, 'push');
			app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
			app.SaveButton.Enable = 'off';
			app.SaveButton.Text = 'Save!';
			app.ExitButton = uibutton(app.ControlPanel, 'push');
			app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
			app.ExitButton.Icon = 'stop.png';
			app.ExitButton.Text = 'Exit!';
			app.SendTriggersSwitchLabel = uilabel(app.ControlPanel);
			app.SendTriggersSwitchLabel.Text = 'Send Triggers?';
			app.SendTriggersSwitch = uiswitch(app.ControlPanel, 'rocker');
			app.DebugModeLabel = uilabel(app.ControlPanel);
			app.DebugModeLabel.Text = 'Debug Mode?';
			app.DebugModeSwitch = uiswitch(app.ControlPanel, 'rocker');
			app.FixOnlySwitchLabel = uilabel(app.ControlPanel);
			app.FixOnlySwitchLabel.Text = 'Fix Only?';
			app.FixOnlySwitch = uiswitch(app.ControlPanel, 'rocker');
			app.EyelinkPanel = uipanel(app.SettingsTab);
			app.EyelinkPanel.Title = 'Eyelink';
			app.SampleRateKnobLabel = uilabel(app.EyelinkPanel);
			app.SampleRateKnobLabel.Text = 'Sample Rate';
			app.SampleRateKnob = uiknob(app.EyelinkPanel, 'discrete');
			app.SampleRateKnob.Items = {'250', '500', '1000', '2000'};
			app.SampleRateKnob.Value = '500';
			app.CalibPointsKnobLabel = uilabel(app.EyelinkPanel);
			app.CalibPointsKnobLabel.Text = 'Calib Points';
			app.CalibPointsKnob = uiknob(app.EyelinkPanel, 'discrete');
			app.CalibPointsKnob.Items = {'HV3', 'HV5', 'HV9', 'HV13'};
			app.CalibPointsKnob.Value = 'HV5';
			app.DummySwitchLabel = uilabel(app.EyelinkPanel);
			app.DummySwitchLabel.Text = 'Dummy';
			app.DummySwitch = uiswitch(app.EyelinkPanel, 'rocker');
			app.DummySwitch.Tooltip = {'Dummy mode for eyelink?'};
			app.XEditFieldLabel = uilabel(app.EyelinkPanel);
			app.XEditFieldLabel.Text = 'X';
			app.XEditField = uieditfield(app.EyelinkPanel, 'numeric');
			app.XEditField.Tooltip = {'X location for fixation window'};
			app.YEditFieldLabel = uilabel(app.EyelinkPanel);
			app.YEditFieldLabel.Text = 'Y';
			app.YEditField = uieditfield(app.EyelinkPanel, 'numeric');
			app.YEditField.Tooltip = {'Y location for fixation window'};
			app.InitEditFieldLabel = uilabel(app.EyelinkPanel);
			app.InitEditFieldLabel.Text = 'Init';
			app.InitEditField = uieditfield(app.EyelinkPanel, 'numeric');
			app.InitEditField.Tooltip = {'Fixation initiation time (s)'};
			app.InitEditField.Value = 1;
			app.DurationEditFieldLabel = uilabel(app.EyelinkPanel);
			app.DurationEditFieldLabel.Text = 'Duration';
			app.DurationEditField = uieditfield(app.EyelinkPanel, 'numeric');
			app.DurationEditField.Limits = [0.1 20];
			app.DurationEditField.Tooltip = {'Fixation maintain time (s)'};
			app.DurationEditField.Value = 0.5;
			app.RadiusEditFieldLabel = uilabel(app.EyelinkPanel);
			app.RadiusEditFieldLabel.Text = 'Radius';
			app.RadiusEditField = uieditfield(app.EyelinkPanel, 'numeric');
			app.RadiusEditField.Limits = [0.1 20];
			app.RadiusEditField.Tooltip = {'Fixation window radius'};
			app.RadiusEditField.Value = 2;
			app.StrictEditFieldLabel = uilabel(app.EyelinkPanel);
			app.StrictEditFieldLabel.Text = 'Strict';
			app.StrictEditField = uieditfield(app.EyelinkPanel, 'numeric');
			app.StrictEditField.Limits = [0 1];
			app.StrictEditField.Tooltip = {'Strict fixation checking?'};
			app.ManualSwitchLabel = uilabel(app.EyelinkPanel);
			app.ManualSwitchLabel.Text = 'Manual';
			app.ManualSwitch = uiswitch(app.EyelinkPanel, 'rocker');
			app.ManualSwitch.Tooltip = {'Use manual (remote) calibration method?'};
			app.CalibPropEditFieldLabel = uilabel(app.EyelinkPanel);
			app.CalibPropEditFieldLabel.Text = 'Calib Prop';
			app.CalibPropEditField = uieditfield(app.EyelinkPanel, 'text');
			app.CalibPropEditField.Value = '0.7  0.7';
			app.TaskParametersPanel = uipanel(app.SettingsTab);
			app.TaskParametersPanel.Title = 'Task Parameters';
			app.CueTimeEditFieldLabel = uilabel(app.TaskParametersPanel);
			app.CueTimeEditFieldLabel.Text = 'Cue Time';
			app.CueTimeEditField = uieditfield(app.TaskParametersPanel, 'numeric');
			app.CueTimeEditField.Limits = [0.01 20];
			app.CueTimeEditField.Value = 0.75;
			app.CueSizeEditFieldLabel = uilabel(app.TaskParametersPanel);
			app.CueSizeEditFieldLabel.Enable = 'off';
			app.CueSizeEditFieldLabel.Text = 'Cue Size';
			app.CueSizeEditField = uieditfield(app.TaskParametersPanel, 'numeric');
			app.CueSizeEditField.Limits = [0.01 20];
			app.CueSizeEditField.Enable = 'off';
			app.CueSizeEditField.Value = 5;
			app.DelayTimeEditField_2Label = uilabel(app.TaskParametersPanel);
			app.DelayTimeEditField_2Label.Text = 'Delay Time';
			app.DelayTimeEditField = uieditfield(app.TaskParametersPanel, 'text');
			app.DelayTimeEditField.Value = '0.6 1';
			app.PunishTimeEditFieldLabel = uilabel(app.TaskParametersPanel);
			app.PunishTimeEditFieldLabel.Text = 'Punish Time';
			app.PunishTimeEditField = uieditfield(app.TaskParametersPanel, 'numeric');
			app.PunishTimeEditField.Limits = [0 40];
			app.PunishTimeEditField.ValueDisplayFormat = '%11.5g';
			app.PunishTimeEditField.Value = 3;
			app.TrialBlocksEditFieldLabel = uilabel(app.TaskParametersPanel);
			app.TrialBlocksEditFieldLabel.Text = 'Trial Blocks';
			app.TrialBlocksEditField = uieditfield(app.TaskParametersPanel, 'numeric');
			app.TrialBlocksEditField.Limits = [1 600];
			app.TrialBlocksEditField.Value = 5;
			app.RewardmsEditFieldLabel = uilabel(app.TaskParametersPanel);
			app.RewardmsEditFieldLabel.Text = 'Reward (ms)';
			app.RewardmsEditField = uieditfield(app.TaskParametersPanel, 'numeric');
			app.RewardmsEditField.Limits = [1 2000];
			app.RewardmsEditField.Value = 150;
			app.InitiateChoiceEditFieldLabel = uilabel(app.TaskParametersPanel);
			app.InitiateChoiceEditFieldLabel.Text = 'Initiate Choice';
			app.InitiateChoiceEditField = uieditfield(app.TaskParametersPanel, 'numeric');
			app.InitiateChoiceEditField.Limits = [0 600];
			app.InitiateChoiceEditField.Value = 0.75;
			app.MaintainChoiceEditFieldLabel = uilabel(app.TaskParametersPanel);
			app.MaintainChoiceEditFieldLabel.Text = 'Maintain Choice';
			app.MaintainChoiceEditField = uieditfield(app.TaskParametersPanel, 'numeric');
			app.MaintainChoiceEditField.Limits = [0 600];
			app.MaintainChoiceEditField.Value = 0.5;
			app.ITIEditFieldLabel = uilabel(app.TaskParametersPanel);
			app.ITIEditFieldLabel.Text = 'ITI';
			app.ITIEditField = uieditfield(app.TaskParametersPanel, 'numeric');
			app.ITIEditField.Limits = [0 40];
			app.ITIEditField.ValueDisplayFormat = '%11.5g';
			app.ITIEditField.Value = 1;
			app.UseExclusionZonesSwitchLabel = uilabel(app.TaskParametersPanel);
			app.UseExclusionZonesSwitchLabel.Text = 'Use Exclusion Zones?';
			app.UseExclusionZonesSwitch = uiswitch(app.TaskParametersPanel, 'rocker');
			app.UseExclusionZonesSwitch.Tooltip = {'Use exclusion zones around the distractors to stop random saccades to anything but the target'};
			app.UseExclusionZonesSwitch.Value = 'On';
			app.InitiateSaccadeDelaySwitchLabel = uilabel(app.TaskParametersPanel);
			app.InitiateSaccadeDelaySwitchLabel.Text = 'Initiate Saccade Delay?';
			app.InitiateSaccadeDelaySwitch = uiswitch(app.TaskParametersPanel, 'rocker');
			app.InitiateSaccadeDelaySwitch.Tooltip = {'Use an minimum time to initiate the saccade to the target'};
			app.InitiateSaccadeDelaySwitch.Value = 'On';
			app.FixInitTime = uieditfield(app.TaskParametersPanel, 'numeric');
			app.FixInitTime.Tooltip = {'Fix init time in seconds (s) '};
			app.FixInitTime.Value = 0.1;
			app.OnlinePlotsTab = uitab(app.TabGroup);
			app.OnlinePlotsTab.Title = 'Online Plots';
			app.TrainAxes1 = uiaxes(app.OnlinePlotsTab);
			title(app.TrainAxes1, 'Trial Results')
			xlabel(app.TrainAxes1, 'Type')
			ylabel(app.TrainAxes1, '# of trials')
			app.TrainAxes2 = uiaxes(app.OnlinePlotsTab);
			title(app.TrainAxes2, 'Overall Performance')
			xlabel(app.TrainAxes2, 'Trial')
			ylabel(app.TrainAxes2, '% success')
			app.TrainAxes3 = uiaxes(app.OnlinePlotsTab);
			title(app.TrainAxes3, 'Recent Performance')
			xlabel(app.TrainAxes3, 'Trial Average (last 10)')
			ylabel(app.TrainAxes3, '% success')
			app.TrainAxes4 = uiaxes(app.OnlinePlotsTab);
			title(app.TrainAxes4, 'Last Eye Position')
			xlabel(app.TrainAxes4, 'X')
			ylabel(app.TrainAxes4, 'Y')
			zlabel(app.TrainAxes4, 'Z')
			app.TrainAxes4.XLim = [-10 10];
			app.TrainAxes4.YLim = [-10 10];
			app.TrainAxes4.YDir = 'reverse';
			app.TrainAxes4.YTick = [-10 -5 0 5 10];
			app.MoreSettingsTab = uitab(app.TabGroup);
			app.MoreSettingsTab.Title = 'More Settings...';
			app.OthertrainingparametersPanel = uipanel(app.MoreSettingsTab);
			app.OthertrainingparametersPanel.Enable = 'off';
			app.OthertrainingparametersPanel.Title = 'Other training parameters';
			app.InterTrialDelayEditFieldLabel = uilabel(app.OthertrainingparametersPanel);
			app.InterTrialDelayEditFieldLabel.Text = 'InterTrial Delay';
			app.InterTrialDelayEditField = uieditfield(app.OthertrainingparametersPanel, 'numeric');
			app.InterTrialDelayEditField.Limits = [0.01 20];
			app.InterTrialDelayEditField.Value = 0.75;
			app.FixOnlyRewardEditFieldLabel = uilabel(app.OthertrainingparametersPanel);
			app.FixOnlyRewardEditFieldLabel.Text = '% Fix Only Reward';
			app.FixOnlyRewardEditField = uieditfield(app.OthertrainingparametersPanel, 'numeric');
			app.FixOnlyRewardEditField.Limits = [0 100];
			app.FixOnlyRewardEditField.RoundFractionalValues = 'on';
			app.FixOnlyRewardEditField.ValueDisplayFormat = '%.0f';
			app.FixOnlyRewardEditField.Value = 10;
			app.ExclusionZoneEditFieldLabel = uilabel(app.OthertrainingparametersPanel);
			app.ExclusionZoneEditFieldLabel.Text = 'Exclusion Zone';
			app.ExclusionZoneEditField = uieditfield(app.OthertrainingparametersPanel, 'text');
			app.ExclusionZoneEditField.Value = '[ ]';
			app.ArduinoPanel = uipanel(app.MoreSettingsTab);
			app.ArduinoPanel.Enable = 'off';
			app.ArduinoPanel.Title = 'Arduino';
			% Show the figure after all components are created
			app.TrainColourCuedUIFigure.Visible = 'on';
		end
	end

	% App creation and deletion
	methods (Access = public)

		% Construct app
		function app = trainColourCued

			% Create UIFigure and components
			createComponents(app)

			% Register the app with App Designer
			registerApp(app, app.TrainColourCuedUIFigure)

			% Execute the startup function
			runStartupFcn(app, @startupFcn)

			if nargout == 0
				clear app
			end
		end

		% Code that executes before app deletion
		function delete(app)

			% Delete UIFigure when app is deleted
			delete(app.TrainColourCuedUIFigure)
		end
	end
end